classdef sensorsScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NestedSensorArraySystemLabel  matlab.ui.control.Label
        Switch_1                      matlab.ui.control.RockerSwitch
        Switch_2                      matlab.ui.control.RockerSwitch
        Switch_3                      matlab.ui.control.RockerSwitch
        Switch_4                      matlab.ui.control.RockerSwitch
        Switch_5                      matlab.ui.control.RockerSwitch
        Switch_6                      matlab.ui.control.RockerSwitch
        Switch_7                      matlab.ui.control.RockerSwitch
        Switch_8                      matlab.ui.control.RockerSwitch
        Switch_9                      matlab.ui.control.RockerSwitch
        Switch_10                     matlab.ui.control.RockerSwitch
        LampLabel                     matlab.ui.control.Label
        LampFunctionality_1           matlab.ui.control.Lamp
        Lamp_2Label                   matlab.ui.control.Label
        LampFunctionality_2           matlab.ui.control.Lamp
        Lamp_3Label                   matlab.ui.control.Label
        LampFunctionality_3           matlab.ui.control.Lamp
        Label_14                      matlab.ui.control.Label
        LampFunctionality_4           matlab.ui.control.Lamp
        Lamp_5Label                   matlab.ui.control.Label
        LampFunctionality_5           matlab.ui.control.Lamp
        Lamp_6Label                   matlab.ui.control.Label
        LampFunctionality_6           matlab.ui.control.Lamp
        Lamp_7Label                   matlab.ui.control.Label
        LampFunctionality_7           matlab.ui.control.Lamp
        Lamp_8Label                   matlab.ui.control.Label
        LampFunctionality_8           matlab.ui.control.Lamp
        Lamp_9Label                   matlab.ui.control.Label
        LampFunctionality_9           matlab.ui.control.Lamp
        Lamp_10Label                  matlab.ui.control.Label
        LampFunctionality_10          matlab.ui.control.Lamp
        CheckConfigurationButton      matlab.ui.control.Button
        ResetAllSensorsButton         matlab.ui.control.Button
        Lamp_11Label                  matlab.ui.control.Label
        LampUseState_1                matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        LampUseState_2                matlab.ui.control.Lamp
        Label_7                       matlab.ui.control.Label
        LampUseState_4                matlab.ui.control.Lamp
        Label_6                       matlab.ui.control.Label
        LampUseState_3                matlab.ui.control.Lamp
        Label_9                       matlab.ui.control.Label
        LampUseState_6                matlab.ui.control.Lamp
        Label_8                       matlab.ui.control.Label
        LampUseState_5                matlab.ui.control.Lamp
        Label_13                      matlab.ui.control.Label
        LampUseState_10               matlab.ui.control.Lamp
        Label_12                      matlab.ui.control.Label
        LampUseState_9                matlab.ui.control.Lamp
        Label_11                      matlab.ui.control.Label
        LampUseState_8                matlab.ui.control.Lamp
        Label_10                      matlab.ui.control.Label
        LampUseState_7                matlab.ui.control.Lamp
        Switch_11                     matlab.ui.control.RockerSwitch
        Switch_12                     matlab.ui.control.RockerSwitch
        Switch_13                     matlab.ui.control.RockerSwitch
        Switch_14                     matlab.ui.control.RockerSwitch
        Switch_15                     matlab.ui.control.RockerSwitch
        Switch_16                     matlab.ui.control.RockerSwitch
        Switch_17                     matlab.ui.control.RockerSwitch
        Switch_18                     matlab.ui.control.RockerSwitch
        Switch_19                     matlab.ui.control.RockerSwitch
        Switch_20                     matlab.ui.control.RockerSwitch
        Label_25                      matlab.ui.control.Label
        LampFunctionality_11          matlab.ui.control.Lamp
        Label_27                      matlab.ui.control.Label
        LampFunctionality_12          matlab.ui.control.Lamp
        Label_28                      matlab.ui.control.Label
        LampFunctionality_13          matlab.ui.control.Lamp
        Label_15                      matlab.ui.control.Label
        LampFunctionality_14          matlab.ui.control.Lamp
        Label_29                      matlab.ui.control.Label
        LampFunctionality_15          matlab.ui.control.Lamp
        Label_30                      matlab.ui.control.Label
        LampFunctionality_16          matlab.ui.control.Lamp
        Label_31                      matlab.ui.control.Label
        LampFunctionality_17          matlab.ui.control.Lamp
        Label_32                      matlab.ui.control.Label
        LampFunctionality_18          matlab.ui.control.Lamp
        Label_33                      matlab.ui.control.Label
        LampFunctionality_19          matlab.ui.control.Lamp
        Label_34                      matlab.ui.control.Label
        LampFunctionality_20          matlab.ui.control.Lamp
        Label_26                      matlab.ui.control.Label
        LampUseState_11               matlab.ui.control.Lamp
        Label_16                      matlab.ui.control.Label
        LampUseState_12               matlab.ui.control.Lamp
        Label_17                      matlab.ui.control.Label
        LampUseState_13               matlab.ui.control.Lamp
        Label_18                      matlab.ui.control.Label
        LampUseState_14               matlab.ui.control.Lamp
        Label_19                      matlab.ui.control.Label
        LampUseState_15               matlab.ui.control.Lamp
        Label_20                      matlab.ui.control.Label
        LampUseState_16               matlab.ui.control.Lamp
        Label_21                      matlab.ui.control.Label
        LampUseState_17               matlab.ui.control.Lamp
        Label_22                      matlab.ui.control.Label
        LampUseState_18               matlab.ui.control.Lamp
        Label_23                      matlab.ui.control.Label
        LampUseState_19               matlab.ui.control.Lamp
        Label_24                      matlab.ui.control.Label
        LampUseState_20               matlab.ui.control.Lamp
        Switch_21                     matlab.ui.control.RockerSwitch
        Switch_22                     matlab.ui.control.RockerSwitch
        Switch_23                     matlab.ui.control.RockerSwitch
        Switch_24                     matlab.ui.control.RockerSwitch
        Switch_25                     matlab.ui.control.RockerSwitch
        Switch_26                     matlab.ui.control.RockerSwitch
        Switch_27                     matlab.ui.control.RockerSwitch
        Switch_28                     matlab.ui.control.RockerSwitch
        Switch_29                     matlab.ui.control.RockerSwitch
        Switch_30                     matlab.ui.control.RockerSwitch
        Switch_31                     matlab.ui.control.RockerSwitch
        Switch_32                     matlab.ui.control.RockerSwitch
        Switch_33                     matlab.ui.control.RockerSwitch
        Switch_34                     matlab.ui.control.RockerSwitch
        Switch_35                     matlab.ui.control.RockerSwitch
        Switch_36                     matlab.ui.control.RockerSwitch
        Switch_37                     matlab.ui.control.RockerSwitch
        Switch_38                     matlab.ui.control.RockerSwitch
        Switch_39                     matlab.ui.control.RockerSwitch
        Switch_40                     matlab.ui.control.RockerSwitch
        Label_65                      matlab.ui.control.Label
        LampFunctionality_21          matlab.ui.control.Lamp
        Label_66                      matlab.ui.control.Label
        LampFunctionality_22          matlab.ui.control.Lamp
        Label_67                      matlab.ui.control.Label
        LampFunctionality_23          matlab.ui.control.Lamp
        Label_35                      matlab.ui.control.Label
        LampFunctionality_24          matlab.ui.control.Lamp
        Label_68                      matlab.ui.control.Label
        LampFunctionality_25          matlab.ui.control.Lamp
        Label_69                      matlab.ui.control.Label
        LampFunctionality_26          matlab.ui.control.Lamp
        Label_70                      matlab.ui.control.Label
        LampFunctionality_27          matlab.ui.control.Lamp
        Label_71                      matlab.ui.control.Label
        LampFunctionality_28          matlab.ui.control.Lamp
        Label_72                      matlab.ui.control.Label
        LampFunctionality_29          matlab.ui.control.Lamp
        Label_73                      matlab.ui.control.Label
        LampFunctionality_30          matlab.ui.control.Lamp
        Label_74                      matlab.ui.control.Label
        LampUseState_21               matlab.ui.control.Lamp
        Label_36                      matlab.ui.control.Label
        LampUseState_22               matlab.ui.control.Lamp
        Label_37                      matlab.ui.control.Label
        LampUseState_23               matlab.ui.control.Lamp
        Label_38                      matlab.ui.control.Label
        LampUseState_24               matlab.ui.control.Lamp
        Label_39                      matlab.ui.control.Label
        LampUseState_25               matlab.ui.control.Lamp
        Label_40                      matlab.ui.control.Label
        LampUseState_26               matlab.ui.control.Lamp
        Label_41                      matlab.ui.control.Label
        LampUseState_27               matlab.ui.control.Lamp
        Label_42                      matlab.ui.control.Label
        LampUseState_28               matlab.ui.control.Lamp
        Label_43                      matlab.ui.control.Label
        LampUseState_29               matlab.ui.control.Lamp
        Label_44                      matlab.ui.control.Label
        LampUseState_30               matlab.ui.control.Lamp
        Label_45                      matlab.ui.control.Label
        LampFunctionality_31          matlab.ui.control.Lamp
        Label_46                      matlab.ui.control.Label
        LampFunctionality_32          matlab.ui.control.Lamp
        Label_47                      matlab.ui.control.Label
        LampFunctionality_33          matlab.ui.control.Lamp
        Label_48                      matlab.ui.control.Label
        LampFunctionality_34          matlab.ui.control.Lamp
        Label_49                      matlab.ui.control.Label
        LampFunctionality_35          matlab.ui.control.Lamp
        Label_50                      matlab.ui.control.Label
        LampFunctionality_36          matlab.ui.control.Lamp
        Label_51                      matlab.ui.control.Label
        LampFunctionality_37          matlab.ui.control.Lamp
        Label_52                      matlab.ui.control.Label
        LampFunctionality_38          matlab.ui.control.Lamp
        Label_53                      matlab.ui.control.Label
        LampFunctionality_39          matlab.ui.control.Lamp
        Label_54                      matlab.ui.control.Label
        LampFunctionality_40          matlab.ui.control.Lamp
        Label_55                      matlab.ui.control.Label
        LampUseState_31               matlab.ui.control.Lamp
        Label_56                      matlab.ui.control.Label
        LampUseState_32               matlab.ui.control.Lamp
        Label_57                      matlab.ui.control.Label
        LampUseState_33               matlab.ui.control.Lamp
        Label_58                      matlab.ui.control.Label
        LampUseState_34               matlab.ui.control.Lamp
        Label_59                      matlab.ui.control.Label
        LampUseState_35               matlab.ui.control.Lamp
        Label_60                      matlab.ui.control.Label
        LampUseState_36               matlab.ui.control.Lamp
        Label_61                      matlab.ui.control.Label
        LampUseState_37               matlab.ui.control.Lamp
        Label_62                      matlab.ui.control.Label
        LampUseState_38               matlab.ui.control.Lamp
        Label_63                      matlab.ui.control.Label
        LampUseState_39               matlab.ui.control.Lamp
        Label_64                      matlab.ui.control.Label
        LampUseState_40               matlab.ui.control.Lamp
        Switch_41                     matlab.ui.control.RockerSwitch
        Switch_42                     matlab.ui.control.RockerSwitch
        Switch_43                     matlab.ui.control.RockerSwitch
        Switch_44                     matlab.ui.control.RockerSwitch
        Switch_45                     matlab.ui.control.RockerSwitch
        Switch_46                     matlab.ui.control.RockerSwitch
        Switch_47                     matlab.ui.control.RockerSwitch
        Switch_48                     matlab.ui.control.RockerSwitch
        Switch_49                     matlab.ui.control.RockerSwitch
        Switch_50                     matlab.ui.control.RockerSwitch
        Switch_51                     matlab.ui.control.RockerSwitch
        Switch_52                     matlab.ui.control.RockerSwitch
        Switch_53                     matlab.ui.control.RockerSwitch
        Switch_54                     matlab.ui.control.RockerSwitch
        Switch_55                     matlab.ui.control.RockerSwitch
        Switch_56                     matlab.ui.control.RockerSwitch
        Switch_57                     matlab.ui.control.RockerSwitch
        Switch_58                     matlab.ui.control.RockerSwitch
        Switch_59                     matlab.ui.control.RockerSwitch
        Switch_60                     matlab.ui.control.RockerSwitch
        Switch_61                     matlab.ui.control.RockerSwitch
        Switch_62                     matlab.ui.control.RockerSwitch
        Switch_63                     matlab.ui.control.RockerSwitch
        Switch_64                     matlab.ui.control.RockerSwitch
        Label_113                     matlab.ui.control.Label
        LampFunctionality_41          matlab.ui.control.Lamp
        Label_114                     matlab.ui.control.Label
        LampFunctionality_42          matlab.ui.control.Lamp
        Label_115                     matlab.ui.control.Label
        LampFunctionality_43          matlab.ui.control.Lamp
        Label_75                      matlab.ui.control.Label
        LampFunctionality_44          matlab.ui.control.Lamp
        Label_116                     matlab.ui.control.Label
        LampFunctionality_45          matlab.ui.control.Lamp
        Label_117                     matlab.ui.control.Label
        LampFunctionality_46          matlab.ui.control.Lamp
        Label_118                     matlab.ui.control.Label
        LampFunctionality_47          matlab.ui.control.Lamp
        Label_119                     matlab.ui.control.Label
        LampFunctionality_48          matlab.ui.control.Lamp
        Label_120                     matlab.ui.control.Label
        LampFunctionality_49          matlab.ui.control.Lamp
        Label_121                     matlab.ui.control.Label
        LampFunctionality_50          matlab.ui.control.Lamp
        Label_122                     matlab.ui.control.Label
        LampUseState_41               matlab.ui.control.Lamp
        Label_76                      matlab.ui.control.Label
        LampUseState_42               matlab.ui.control.Lamp
        Label_77                      matlab.ui.control.Label
        LampUseState_43               matlab.ui.control.Lamp
        Label_78                      matlab.ui.control.Label
        LampUseState_44               matlab.ui.control.Lamp
        Label_79                      matlab.ui.control.Label
        LampUseState_45               matlab.ui.control.Lamp
        Label_80                      matlab.ui.control.Label
        LampUseState_46               matlab.ui.control.Lamp
        Label_81                      matlab.ui.control.Label
        LampUseState_47               matlab.ui.control.Lamp
        Label_82                      matlab.ui.control.Label
        LampUseState_48               matlab.ui.control.Lamp
        Label_83                      matlab.ui.control.Label
        LampUseState_49               matlab.ui.control.Lamp
        Label_84                      matlab.ui.control.Label
        LampUseState_50               matlab.ui.control.Lamp
        Label_85                      matlab.ui.control.Label
        LampFunctionality_51          matlab.ui.control.Lamp
        Label_86                      matlab.ui.control.Label
        LampFunctionality_52          matlab.ui.control.Lamp
        Label_87                      matlab.ui.control.Label
        LampFunctionality_53          matlab.ui.control.Lamp
        Label_88                      matlab.ui.control.Label
        LampFunctionality_54          matlab.ui.control.Lamp
        Label_89                      matlab.ui.control.Label
        LampFunctionality_55          matlab.ui.control.Lamp
        Label_90                      matlab.ui.control.Label
        LampFunctionality_56          matlab.ui.control.Lamp
        Label_91                      matlab.ui.control.Label
        LampFunctionality_57          matlab.ui.control.Lamp
        Label_92                      matlab.ui.control.Label
        LampFunctionality_58          matlab.ui.control.Lamp
        Label_93                      matlab.ui.control.Label
        LampFunctionality_59          matlab.ui.control.Lamp
        Label_94                      matlab.ui.control.Label
        LampFunctionality_60          matlab.ui.control.Lamp
        Label_95                      matlab.ui.control.Label
        LampUseState_51               matlab.ui.control.Lamp
        Label_96                      matlab.ui.control.Label
        LampUseState_52               matlab.ui.control.Lamp
        Label_97                      matlab.ui.control.Label
        LampUseState_53               matlab.ui.control.Lamp
        Label_98                      matlab.ui.control.Label
        LampUseState_54               matlab.ui.control.Lamp
        Label_99                      matlab.ui.control.Label
        LampUseState_55               matlab.ui.control.Lamp
        Label_100                     matlab.ui.control.Label
        LampUseState_56               matlab.ui.control.Lamp
        Label_101                     matlab.ui.control.Label
        LampUseState_57               matlab.ui.control.Lamp
        Label_102                     matlab.ui.control.Label
        LampUseState_58               matlab.ui.control.Lamp
        Label_103                     matlab.ui.control.Label
        LampUseState_59               matlab.ui.control.Lamp
        Label_104                     matlab.ui.control.Label
        LampUseState_60               matlab.ui.control.Lamp
        Label_105                     matlab.ui.control.Label
        LampFunctionality_61          matlab.ui.control.Lamp
        Label_106                     matlab.ui.control.Label
        LampFunctionality_62          matlab.ui.control.Lamp
        Label_107                     matlab.ui.control.Label
        LampFunctionality_63          matlab.ui.control.Lamp
        Label_108                     matlab.ui.control.Label
        LampFunctionality_64          matlab.ui.control.Lamp
        Label_109                     matlab.ui.control.Label
        LampUseState_61               matlab.ui.control.Lamp
        Label_110                     matlab.ui.control.Label
        LampUseState_62               matlab.ui.control.Lamp
        Label_111                     matlab.ui.control.Label
        LampUseState_63               matlab.ui.control.Lamp
        Label_112                     matlab.ui.control.Label
        LampUseState_64               matlab.ui.control.Lamp
        SNRdBSliderLabel              matlab.ui.control.Label
        SNRdBSlider                   matlab.ui.control.Slider
        AngleSliderLabel              matlab.ui.control.Label
        AngleSlider                   matlab.ui.control.Slider
        FrequencyHzSliderLabel        matlab.ui.control.Label
        FrequencyHzSlider             matlab.ui.control.Slider
        NSAbadPSLButton               matlab.ui.control.Button
        NotanNSAButton                matlab.ui.control.Button
        FailAllSensorsButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        ElementsInUse = []% Description
        sensors
    end
    
    methods (Access = private)
        
        function [ElementsInUse] = SelectConfiguration(app,sensors,SNRdB,angle,freq) 
        % if flag == 0 simulated data, flag == 1 real data
        % sensors is an array of 1s and 0s to simulate on and off sensors

            close all;
            load('sub2s.mat'); 
            load('finalConfigs_good.mat');
            load('sub2s_bad.mat'); 
            load('finalConfigs_bad.mat');

            ElementsInUse = zeros(1,length(sensors));
            f = find(diff([0,sensors,0]==1)); % index of every change
            start1s = f(1:2:end-1);  % Start indices of 1s
            count1s = f(2:2:end)-start1s;  % Consecutive 1s counts
            [maxM,position] = max(count1s); %returns [max consecutive 1s, index in x]
            maxMstart = start1s(position);
            if maxM <2
                msgbox('M is too small. This array cannot function as an NSA.', 'Error', 'error')
            end

            first = start1s(1); 
            last = find(sensors,1,'last'); %Because sensors is 1s and 0s, this will find the last 1
            L_orig = last-first+1;
            sensors = sensors(first:last); %trims any outside zeros

            badcase = 0;
            match = 0; %Determines whether the configuration matches a known one
            while true
                for L = L_orig:-1:7  % check aperture decreasing
                    disp('Im checking a new aperture, good'); disp(num2str(L))
                    sub2aperL = find(cell2mat(sub2s(:,2))==L);  % saves indices of sub2s with aperture L
                    if isempty(sub2aperL) == 0 % there is a valid config of that L
                        lookhere = find(finalConfigs_good(sub2aperL(1):sub2aperL(end),4)<=maxM) + sub2aperL(1)-1; % with valid sub1+ext
                        if isempty(lookhere)==0  % there is a valid config of that L (lookhere is not empty) 
                            for i = lookhere(1):lookhere(end)  % search through the valid sub2s 
                                sub2s_check = sub2s{i,1};  % saves current sub2 being checked
                                for j = 1:(length(sensors)-length(sub2s_check)+1)
                                    if sum(lt(sensors((j):(j+length(sub2s_check)-1)),sub2s_check)) == 0
                                        match = 1
                                        sub2start = j+start1s(1,1)-1;
                                        M = finalConfigs_good(i,1);
                                        p = finalConfigs_good(i,2);
                                        ConfigPick = finalConfigs_good(i,:);
                                        %l = finalConfigs_good(i,3); %aperture

                                        break % out of j = 0:(length(sensors)-length(sub2s_check)) 
                                    end 
                                end
                                if match == 1
                                    break % out of i = lookhere(1):lookhere(end)
                                end
                            end
                        end
                    end
                    if match == 1
                        break % out of L = L_orig:-1:7
                    end
                end     
                if match == 1 
                    break % out of while true -> end function
                end             
            %%%%%%%%%%%%%%%%%%%%%%%%% NOT AS GOOD AS A ULA %%%%%%%%%%%%%%%%%%%%%%%%%%%%
                for L = L_orig:-1:3  % check aperture decreasing
                    disp('Im checking a new aperture, bad'); disp(num2str(L))
                    sub2aperL = find(cell2mat(sub2s_bad(:,2))==L);  % saves indices of sub2s with aperture L
                    if isempty(sub2aperL) == 0 % there is a valid config of that L
                        lookhere = find(finalConfigs_bad(sub2aperL(1):sub2aperL(end),4)<=maxM) + sub2aperL(1)-1; % with valid sub1+ext
                        if isempty(lookhere)==0  % there is a valid config of that L (lookhere is not empty) 
                            for i = lookhere(1):lookhere(end)  % search through the valid sub2s 
                                sub2s_check = sub2s_bad{i,1};  % saves current sub2 being checked 
                                for j = 1:(length(sensors)-length(sub2s_check)+1)
                                    if sum(lt(sensors((j):(j+length(sub2s_check)-1)),sub2s_check)) == 0
                                        badcase = 1;
                                        sub2start = j+start1s(1,1)-1;
                                        M = finalConfigs_bad(i,1);
                                        p = finalConfigs_bad(i,2);
                                        ConfigPick = finalConfigs_bad(i,:);
                                        %l = finalConfigs_bad(i,3); %aperture

                                        break % j = 0:(length(sensors)-length(sub2s_check))
                                    end
                                    if badcase == 1
                                        break % out of i = lookhere(1):lookhere(end)
                                    end
                                end                   
                            end
                        end
                    end
                    if badcase == 1
                        break % out of L = L_orig:-1:7
                    end
                end      
                break % out of while true -> end function
            end %end of while true loop

            %%%% Plot and selecting sensors!!
            if (match == 1)||(badcase == 1)
                ElementsInUse(maxMstart:(maxMstart+ConfigPick(1,4)-1)) = 1;
                ElementsInUse(sub2start:ConfigPick(1,1):(sub2start+length(sub2s_check)-1)) = 1;
%                 if flag == 1   % real data
%                     % ArraySystem.TemporalSpatial(data,filters,processor,plots,M,p,L)
%                     ArraySystem.TemporalSpatial(0,1,2,1,M,p,L); %figure;
%                     % ArraySystem.NestedBP(M, p, L, plots)
%                     ArraySystem.NestedBP(M,p,L,1);
%                elseif flag ==0  % simulated data
                PlotTSBP(app,1,0,2,1,M,p,L,SNRdB,angle,freq);
%                     % ArraySystem.TemporalSpatial(data,filters,processor,plots,M,p,L)
%                 ArraySystem.TemporalSpatial(1,0,2,1,M,p,L,SNRdB,angle); %figure;
%                     % ArraySystem.NestedBP(M, p, L, plots)
%                 ArraySystem.NestedBP(M,p,L,1);
%                   end
                if badcase == 1
                    msgbox('This configuration is NOT guaranteed to accurately and adequately detect the signal.','Warning','warn')
                end
            elseif maxM >= 2
                msgbox('Array cannot be analyzed as an NSA.', 'Error', 'error')
            end            
        end
        
        function PlotTSBP(app,data,filters,processor,plots,M,p,L,SNRdB,angle,freq)
        %%%% This function uses:   
        %%%%%%%      real    data if data = 0  -OR-  simulated data if data = 1
        %%%% AND can apply a filter to the array:
        %%%%%%%      no filter  --> filters = 0  -OR-  with filter --> filters = 1
        %%%% AND applies a processor to the array:
        %%%%%%%  NO processor (ULA) --> processor = 0  -OR-
        %%%%%%%      product --> processor = 1  -OR-   minimum --> processor = 2
        %%%% AND applies an option for plots
        %%%%%%%      no plots --> plots = 0  -OR-  yes plots --> plots = 1 
        %%% ******* simulation data parameters can be ******* 
        %%%   *** added to input arguments if necessary ***
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        f = freq;
            %%%%%%%%%%%%%%% data %%%%%%%%%%%%%%%
            if (data == 0)
                %%%% load path is dependant on the computer being used
                load %%%%%%%%'C:\Users\MaxSlezak\Google Drive\Classes\2018-2019\Senior Design\Measurement_Data\3\138'
                [totalData order]=Rearrange_Data(totalData);
                totalData=totalData(100:40100,3:L);
            elseif (data == 1)
                %%%%%%% Some constant parameters
                %angle = -34;
                %f = 8923.8;%%%%Signal frequency in Hz
                c = 343;%%%%%Signal speed in m/s
                deltat = 1/44100;%%%%Temporal sampling interval in s
                %SNRdB = 5;%%%%SNR in dB
                vars = 1;%%%%Signal variance
                varn = vars*10^(-SNRdB/10);%%%%Noise variance
        
                %%%%To create x = (exp(1j*2*pi*f*t-1j*pi*cosd(-34)*indices)+ ...
                %%%%exp(1j*2*pi*(-f)*t-1j*pi*cosd(-34)*indices)), make a matrix with the t
                %%%%values and indices values first
                times = (0:deltat:1)';
                locations = 0:(L-1);
                [indices,t] = meshgrid(locations,times);
                x = exp(1j*(2*pi*f*t-pi*cosd(angle)*indices))+conj(exp(1j*(2*pi*f*t-pi*cosd(angle)*indices)));
                clear indices t times;
                %%%%To the matrix x above, we need to add white noise
                totalData = x + sqrt(varn/2)*randn(size(x)) + 1i*sqrt(varn/2)*randn(size(x));
            else
                disp('Error: data = 0 or 1')
            end
        
            %%%%%%%%%%%%%%%%% filter %%%%%%%%%%%%%%%%%%%
            if (filters == 1)
                %Apply a highpass filter to remove noise
                band = designfilt('highpassiir','FilterOrder',50,...
                'PassbandFrequency', 3500, 'PassbandRipple',.2,...
                'SampleRate',44100); %This filter passes frequencies above 4000
                totalData=filter(band,totalData);
            elseif (filters == 0)
            else
                disp('Error: filter = 0 or 1')
            end
        
            %%%%%%%%%%%%%%% processor %%%%%%%%%%%%%%%
            if processor == 0
                F = fft(totalData,(L-2)*10,2);
                mF = mean(abs(F));
                mF = 20*log10(fliplr(fftshift(mF)/max(abs(mF))));
                w = linspace(-1,1,length(mF));
            elseif (processor == 1)||(processor == 2)
                sparseindices1 = (1:(M+M*p));%%% We add 1 at the end because MATLAB
                                            %%% index starts at 1, not 0
                sparseindices2 = (1:M:L);%%% We add 1 at the end because MATLAB
                                                 %%% index starts at 1, not 0
                data1 = zeros(size(totalData));
                data2 = zeros(size(totalData));
                data1(:,sparseindices1) = totalData(:,sparseindices1);
                data2(:,sparseindices2) = totalData(:,sparseindices2);
        
                if (processor==1)
                    %%%%Find the product
                    F = fft(data1,L*10,2) .* conj(fft(data2,L*10,2));
                    %%%%Find the average
                    mF = mean(abs(F));
                    %%%%Normalize, fftshift, flip left to right and convert to dB
                    mF = 10*log10(fliplr(fftshift(mF)/max(abs(mF))));
                elseif (processor==2)
                    %%%%Find the minimum of the absolute values
                    F = min(abs(fft(data1,L*10,2)) , abs(fft(data2,L*10,2)));
                    %%%%Find the average
                    mF = mean(abs(F));
                    %%%%Normalize, fftshift, flip left to right and convert to dB
                    mF = 20*log10(fliplr(fftshift(mF)/max(abs(mF))));
                end
                w = linspace(-1,1,length(mF));
            else 
                disp('Error: Input argument must be 1 or 2.');
                return;
            end
        
            %%%%%%%%%%%%%%% analysis plots %%%%%%%%%%%%%%%
            if (plots == 1)
                %%%%%%%%%%% Plot the spatial spectrum %%%%%%%%%%%%
                figure;
                %subplot(2,2,1);
                pos = [0.055 0.6 0.4 0.3]; % [left bottom width height]
                subplot('Position',pos);
                plot(acosd(w),mF,'LineWidth',2);
                set(gca,'FontSize',12);
                ylabel('Power dB','FontWeight','bold', 'FontSize',16);
                xlabel('\theta centered','FontWeight','bold', 'FontSize',16);
                title('Spatial Spectral Estimation','FontWeight','bold','FontSize',20);
                xlim([0 180]); ylim([-20 0]); grid('on');
        
                %%%%%%%%%%% Plot the temporal spectrum %%%%%%%%%%%%
                F = fft(totalData,size(totalData,1)*10);
                mF = mean(abs(F),2);
                mF = fftshift(mF)/max(abs(mF));
                w = linspace(-1,1,length(mF));
        
                %hold on; subplot(2,2,3);
                pos = [0.055 0.125 0.4 0.3]; % [left bottom width height]
                subplot('Position',pos);
                plot(w,20*log10(mF),'LineWidth',2);
                set(gca,'FontSize',12);
                ylabel('Power dB','FontWeight','bold', 'FontSize',16);
                xlabel('\times 0.5f_s, frequency (Hz)','FontWeight','bold', 'FontSize',16);
                title('Temporal Spectral Estimation','FontWeight','bold', 'FontSize',20);
                xlim([-1 1]); ylim([-40 0]); grid('on');
        
            elseif (plots == 0)
            else
                disp('Error; plots must be 0 or 1')
            end
        
            %M = M;
            N = ceil(L/M);  
        %%% add1 and add2 are the additional sensors in subarray1 and subarray2
            add1 = M*p;
            add2 = 0;
        %%% U1 and U2 are undersampling factors
            U1 = 1;
            U2 = M;
        
            uDelta = 0.001;
            u = -1:uDelta:1;
            %%%% LinearBP arguments: 
            %%%% 1. 0 for no plots %%% 2. elements in subarray 1 
            %%%% 3. extra sensors in subarray %%% 4. sensor spacing (lambda=0.5)
            [B1, ~] = ArraySystem.LinearBP(0, M+add1, 0, U1*0.5);
             B1 = B1/max(abs(B1));
            [B2, ~] = ArraySystem.LinearBP(0, N+add2, 0, U2*0.5);
             B2 = B2/max(abs(B2));
        
            Bmin = min(abs([B1;B2]));
            Bmin = 20*log10(abs(Bmin));
        
            Bprod = B1.*conj(B2);
            Bprod = 10*log10(abs(Bprod));
        
            if plots == 1
                %subplot(2,2,[2 4]); 
                pos = [0.525 0.125 0.45 0.8]; % [left bottom width height]
                subplot('Position',pos);
                set(gca, 'FontSize', 12);
                plot(u, 20*log10(abs(B1)), 'LineStyle', '-.', 'LineWidth', 1, 'Color', 'Blue');
                hold on;
                plot(u, 20*log10(abs(B2)), 'LineStyle', '-.', 'LineWidth', 3, 'Color', 'Red');
                hold on; grid on;
                plot(u, Bmin, 'LineWidth', 2, 'Color', 'Black');
                hold on;
                plot(u, Bprod, '--','LineWidth', 1, 'Color', [0 0.6 0]);
                hold on;
                plot([-1 1],[-13 -13],'--','LineWidth',1,'Color','k');
                xlabel('u=cos(\theta)', 'FontSize', 16, 'FontWeight', 'Bold');
                ylabel('20log|B(u)|, dB', 'FontSize', 16, 'FontWeight', 'Bold');
                legend('Subarray 1','Subarray 2', 'Min', 'Product');
                xlim([-1 1]);
                ylim([-30 0]);
                title(sprintf('NSA Beampattern with L = %d, M = %d, add1 = %d ',L,M,add1), 'FontSize', 20, 'FontWeight', 'Bold')
                set(gcf, 'Units', 'Normalized', 'OuterPosition', [0.2, 0.01, 0.8, 0.65]);
                %movegui([-30 50]);
            elseif plots == 0
            else
                error('plots must be 0 or 1')
            end   
        end
    end
    

    methods (Access = private)

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            switch app.Switch_3.Value
                case 'On'
                    app.LampFunctionality_3.Color = [0, 1, 0];
                    app.sensors(1,3) = 1;
                case 'Off'
                    app.LampFunctionality_3.Color = [1, 0, 0]; 
                    app.sensors(1,3) = 0;
            end
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            switch app.Switch_4.Value
                case 'On'
                    app.LampFunctionality_4.Color = [0,1,0];
                    app.sensors(1,4) = 1;
                case 'Off'
                    app.LampFunctionality_4.Color = [1,0,0]; 
                    app.sensors(1,4) = 0;
            end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            switch app.Switch_5.Value
                case 'On'
                    app.LampFunctionality_5.Color = [0,1,0];
                    app.sensors(1,5) = 1;
                case 'Off'
                    app.LampFunctionality_5.Color = [1,0,0]; 
                    app.sensors(1,5) = 0;
            end
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            switch app.Switch_6.Value
                case 'On'
                    app.LampFunctionality_6.Color = [0,1,0];
                    app.sensors(1,6) = 1;
                case 'Off'
                    app.LampFunctionality_6.Color = [1,0,0]; 
                    app.sensors(1,6) = 0;
            end
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            switch app.Switch_7.Value
                case 'On'
                    app.LampFunctionality_7.Color = [0,1,0];
                    app.sensors(1,7) = 1;
                case 'Off'
                    app.LampFunctionality_7.Color = [1,0,0]; 
                    app.sensors(1,7) = 0;
            end
        end

        % Value changed function: Switch_8
        function Switch_8ValueChanged(app, event)
            switch app.Switch_8.Value
                case 'On'
                    app.LampFunctionality_8.Color = [0,1,0];
                    app.sensors(1,8) = 1;
                case 'Off'
                    app.LampFunctionality_8.Color = [1,0,0]; 
                    app.sensors(1,8) = 0;
            end
        end

        % Value changed function: Switch_9
        function Switch_9ValueChanged(app, event)
            switch app.Switch_9.Value
                case 'On'
                    app.LampFunctionality_9.Color = [0,1,0];
                    app.sensors(1,9) = 1;
                case 'Off'
                    app.LampFunctionality_9.Color = [1,0,0]; 
                    app.sensors(1,9) = 0;
            end
        end

        % Value changed function: Switch_10
        function Switch_10ValueChanged(app, event)
            switch app.Switch_10.Value
                case 'On'
                    app.LampFunctionality_10.Color = [0,1,0];
                    app.sensors(1,10) = 1;
                case 'Off'
                    app.LampFunctionality_10.Color = [1,0,0]; 
                    app.sensors(1,10) = 0;
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            switch app.Switch_2.Value
                case 'On'
                    app.LampFunctionality_2.Color = [0,1,0];
                    app.sensors(1,2) = 1;
                case 'Off'
                    app.LampFunctionality_2.Color = [1,0,0]; 
                    app.sensors(1,2) = 0;
            end
        end

        % Value changed function: Switch_1
        function Switch_1ValueChanged(app, event)
            switch app.Switch_1.Value
                case 'On'
                    app.LampFunctionality_1.Color = [0,1,0];
                    app.sensors(1,1) = 1;
                case 'Off'
                    app.LampFunctionality_1.Color = [1,0,0]; 
                    app.sensors(1,1) = 0;
            end
        end

        % Button pushed function: CheckConfigurationButton
        function CheckConfigurationButtonPushed(app, event)
            app.sensors;
            app.ElementsInUse;
            SNRdB = app.SNRdBSlider.Value;
            angle = app.AngleSlider.Value;
            freq = app.FrequencyHzSlider.Value;
%             if (sum(lt(app.sensors,app.ElementsInUse)) > 0)%||(sum(app.sensors)>sum(app.ElementsInUse))
            UseState = SelectConfiguration(app,app.sensors,SNRdB,angle,freq);
            app.LampUseState_1.Color = [1,1,1] - UseState(1, 1).*[1,1,0];
            app.LampUseState_2.Color = [1,1,1] - UseState(1, 2).*[1,1,0];
            app.LampUseState_3.Color = [1,1,1] - UseState(1, 3).*[1,1,0];
            app.LampUseState_4.Color = [1,1,1] - UseState(1, 4).*[1,1,0]; 
            app.LampUseState_5.Color = [1,1,1] - UseState(1, 5).*[1,1,0];
            app.LampUseState_6.Color = [1,1,1] - UseState(1, 6).*[1,1,0];
            app.LampUseState_7.Color = [1,1,1] - UseState(1, 7).*[1,1,0];
            app.LampUseState_8.Color = [1,1,1] - UseState(1, 8).*[1,1,0];
            app.LampUseState_9.Color = [1,1,1] - UseState(1, 9).*[1,1,0];
            app.LampUseState_10.Color = [1,1,1] - UseState(1, 10).*[1,1,0];
            app.LampUseState_11.Color = [1,1,1] - UseState(1, 11).*[1,1,0];
            app.LampUseState_12.Color = [1,1,1] - UseState(1, 12).*[1,1,0];
            app.LampUseState_13.Color = [1,1,1] - UseState(1, 13).*[1,1,0];
            app.LampUseState_14.Color = [1,1,1] - UseState(1, 14).*[1,1,0]; 
            app.LampUseState_15.Color = [1,1,1] - UseState(1, 15).*[1,1,0];
            app.LampUseState_16.Color = [1,1,1] - UseState(1, 16).*[1,1,0];
            app.LampUseState_17.Color = [1,1,1] - UseState(1, 17).*[1,1,0];
            app.LampUseState_18.Color = [1,1,1] - UseState(1, 18).*[1,1,0];
            app.LampUseState_19.Color = [1,1,1] - UseState(1, 19).*[1,1,0];
            app.LampUseState_20.Color = [1,1,1] - UseState(1, 20).*[1,1,0];
            app.LampUseState_21.Color = [1,1,1] - UseState(1, 21).*[1,1,0];
            app.LampUseState_22.Color = [1,1,1] - UseState(1, 22).*[1,1,0];
            app.LampUseState_23.Color = [1,1,1] - UseState(1, 23).*[1,1,0];
            app.LampUseState_24.Color = [1,1,1] - UseState(1, 24).*[1,1,0]; 
            app.LampUseState_25.Color = [1,1,1] - UseState(1, 25).*[1,1,0];
            app.LampUseState_26.Color = [1,1,1] - UseState(1, 26).*[1,1,0];
            app.LampUseState_27.Color = [1,1,1] - UseState(1, 27).*[1,1,0];
            app.LampUseState_28.Color = [1,1,1] - UseState(1, 28).*[1,1,0];
            app.LampUseState_29.Color = [1,1,1] - UseState(1, 29).*[1,1,0];
            app.LampUseState_30.Color = [1,1,1] - UseState(1, 30).*[1,1,0];
            app.LampUseState_31.Color = [1,1,1] - UseState(1, 31).*[1,1,0];
            app.LampUseState_32.Color = [1,1,1] - UseState(1, 32).*[1,1,0];
            app.LampUseState_33.Color = [1,1,1] - UseState(1, 33).*[1,1,0];
            app.LampUseState_34.Color = [1,1,1] - UseState(1, 34).*[1,1,0]; 
            app.LampUseState_35.Color = [1,1,1] - UseState(1, 35).*[1,1,0];
            app.LampUseState_36.Color = [1,1,1] - UseState(1, 36).*[1,1,0];
            app.LampUseState_37.Color = [1,1,1] - UseState(1, 37).*[1,1,0];
            app.LampUseState_38.Color = [1,1,1] - UseState(1, 38).*[1,1,0];
            app.LampUseState_39.Color = [1,1,1] - UseState(1, 39).*[1,1,0];
            app.LampUseState_40.Color = [1,1,1] - UseState(1, 40).*[1,1,0];
            app.LampUseState_41.Color = [1,1,1] - UseState(1, 41).*[1,1,0];
            app.LampUseState_42.Color = [1,1,1] - UseState(1, 42).*[1,1,0];
            app.LampUseState_43.Color = [1,1,1] - UseState(1, 43).*[1,1,0];
            app.LampUseState_44.Color = [1,1,1] - UseState(1, 44).*[1,1,0]; 
            app.LampUseState_45.Color = [1,1,1] - UseState(1, 45).*[1,1,0];
            app.LampUseState_46.Color = [1,1,1] - UseState(1, 46).*[1,1,0];
            app.LampUseState_47.Color = [1,1,1] - UseState(1, 47).*[1,1,0];
            app.LampUseState_48.Color = [1,1,1] - UseState(1, 48).*[1,1,0];
            app.LampUseState_49.Color = [1,1,1] - UseState(1, 49).*[1,1,0];
            app.LampUseState_50.Color = [1,1,1] - UseState(1, 50).*[1,1,0];
            app.LampUseState_51.Color = [1,1,1] - UseState(1, 51).*[1,1,0];
            app.LampUseState_52.Color = [1,1,1] - UseState(1, 52).*[1,1,0];
            app.LampUseState_53.Color = [1,1,1] - UseState(1, 53).*[1,1,0];
            app.LampUseState_54.Color = [1,1,1] - UseState(1, 54).*[1,1,0]; 
            app.LampUseState_55.Color = [1,1,1] - UseState(1, 55).*[1,1,0];
            app.LampUseState_56.Color = [1,1,1] - UseState(1, 56).*[1,1,0];
            app.LampUseState_57.Color = [1,1,1] - UseState(1, 57).*[1,1,0];
            app.LampUseState_58.Color = [1,1,1] - UseState(1, 58).*[1,1,0];
            app.LampUseState_59.Color = [1,1,1] - UseState(1, 59).*[1,1,0];
            app.LampUseState_60.Color = [1,1,1] - UseState(1, 60).*[1,1,0];
            app.LampUseState_61.Color = [1,1,1] - UseState(1, 61).*[1,1,0];
            app.LampUseState_62.Color = [1,1,1] - UseState(1, 62).*[1,1,0];
            app.LampUseState_63.Color = [1,1,1] - UseState(1, 63).*[1,1,0];
            app.LampUseState_64.Color = [1,1,1] - UseState(1, 64).*[1,1,0]; 
%             end
        end

        % Button pushed function: ResetAllSensorsButton
        function ResetAllSensorsButtonPushed(app, event)
            %set all sensors to funtioning (green) and in use (blue)
            app.ElementsInUse = ones(1,64);
            app.sensors = ones(1,64);
            
            app.Switch_1.Value = 'On';
            app.LampFunctionality_1.Color = [0,1,0];
            app.LampUseState_1.Color = [0,0,1];
            
            app.Switch_2.Value = 'On';
            app.LampFunctionality_2.Color = [0,1,0];
            app.LampUseState_2.Color = [0,0,1];
            
            app.Switch_3.Value = 'On';
            app.LampFunctionality_3.Color = [0,1,0];
            app.LampUseState_3.Color = [0,0,1];
            
            app.Switch_4.Value = 'On';
            app.LampFunctionality_4.Color = [0,1,0];
            app.LampUseState_4.Color = [0,0,1];
            
            app.Switch_5.Value = 'On';
            app.LampFunctionality_5.Color = [0,1,0];
            app.LampUseState_5.Color = [0,0,1];
            
            app.Switch_6.Value = 'On';
            app.LampFunctionality_6.Color = [0,1,0];
            app.LampUseState_6.Color = [0,0,1];
            
            app.Switch_7.Value = 'On';
            app.LampFunctionality_7.Color = [0,1,0];
            app.LampUseState_7.Color = [0,0,1];
            
            app.Switch_8.Value = 'On';
            app.LampFunctionality_8.Color = [0,1,0];
            app.LampUseState_8.Color = [0,0,1];
            
            app.Switch_9.Value = 'On';
            app.LampFunctionality_9.Color = [0,1,0];
            app.LampUseState_9.Color = [0,0,1];
            
            app.Switch_10.Value = 'On';
            app.LampFunctionality_10.Color = [0,1,0];
            app.LampUseState_10.Color = [0,0,1];

            app.Switch_11.Value = 'On';
            app.LampFunctionality_11.Color = [0,1,0];
            app.LampUseState_11.Color = [0,0,1];
            
            app.Switch_12.Value = 'On';
            app.LampFunctionality_12.Color = [0,1,0];
            app.LampUseState_12.Color = [0,0,1];
            
            app.Switch_13.Value = 'On';
            app.LampFunctionality_13.Color = [0,1,0];
            app.LampUseState_13.Color = [0,0,1];
            
            app.Switch_14.Value = 'On';
            app.LampFunctionality_14.Color = [0,1,0];
            app.LampUseState_14.Color = [0,0,1];
            
            app.Switch_15.Value = 'On';
            app.LampFunctionality_15.Color = [0,1,0];
            app.LampUseState_15.Color = [0,0,1];
            
            app.Switch_16.Value = 'On';
            app.LampFunctionality_16.Color = [0,1,0];
            app.LampUseState_16.Color = [0,0,1];
            
            app.Switch_17.Value = 'On';
            app.LampFunctionality_17.Color = [0,1,0];
            app.LampUseState_17.Color = [0,0,1];
            
            app.Switch_18.Value = 'On';
            app.LampFunctionality_18.Color = [0,1,0];
            app.LampUseState_18.Color = [0,0,1];
            
            app.Switch_19.Value = 'On';
            app.LampFunctionality_19.Color = [0,1,0];
            app.LampUseState_19.Color = [0,0,1];
            
            app.Switch_20.Value = 'On';
            app.LampFunctionality_20.Color = [0,1,0];
            app.LampUseState_20.Color = [0,0,1];

            app.Switch_21.Value = 'On';
            app.LampFunctionality_21.Color = [0,1,0];
            app.LampUseState_21.Color = [0,0,1];
            
            app.Switch_22.Value = 'On';
            app.LampFunctionality_22.Color = [0,1,0];
            app.LampUseState_22.Color = [0,0,1];
            
            app.Switch_23.Value = 'On';
            app.LampFunctionality_23.Color = [0,1,0];
            app.LampUseState_23.Color = [0,0,1];
            
            app.Switch_24.Value = 'On';
            app.LampFunctionality_24.Color = [0,1,0];
            app.LampUseState_24.Color = [0,0,1];
            
            app.Switch_25.Value = 'On';
            app.LampFunctionality_25.Color = [0,1,0];
            app.LampUseState_25.Color = [0,0,1];
            
            app.Switch_26.Value = 'On';
            app.LampFunctionality_26.Color = [0,1,0];
            app.LampUseState_26.Color = [0,0,1];
            
            app.Switch_27.Value = 'On';
            app.LampFunctionality_27.Color = [0,1,0];
            app.LampUseState_27.Color = [0,0,1];
            
            app.Switch_28.Value = 'On';
            app.LampFunctionality_28.Color = [0,1,0];
            app.LampUseState_28.Color = [0,0,1];
            
            app.Switch_29.Value = 'On';
            app.LampFunctionality_29.Color = [0,1,0];
            app.LampUseState_29.Color = [0,0,1];
            
            app.Switch_30.Value = 'On';
            app.LampFunctionality_30.Color = [0,1,0];
            app.LampUseState_30.Color = [0,0,1];

            app.Switch_31.Value = 'On';
            app.LampFunctionality_31.Color = [0,1,0];
            app.LampUseState_31.Color = [0,0,1];
            
            app.Switch_32.Value = 'On';
            app.LampFunctionality_32.Color = [0,1,0];
            app.LampUseState_32.Color = [0,0,1];
            
            app.Switch_33.Value = 'On';
            app.LampFunctionality_33.Color = [0,1,0];
            app.LampUseState_33.Color = [0,0,1];
            
            app.Switch_34.Value = 'On';
            app.LampFunctionality_34.Color = [0,1,0];
            app.LampUseState_34.Color = [0,0,1];
            
            app.Switch_35.Value = 'On';
            app.LampFunctionality_35.Color = [0,1,0];
            app.LampUseState_35.Color = [0,0,1];
            
            app.Switch_36.Value = 'On';
            app.LampFunctionality_36.Color = [0,1,0];
            app.LampUseState_36.Color = [0,0,1];
            
            app.Switch_37.Value = 'On';
            app.LampFunctionality_37.Color = [0,1,0];
            app.LampUseState_37.Color = [0,0,1];
            
            app.Switch_38.Value = 'On';
            app.LampFunctionality_38.Color = [0,1,0];
            app.LampUseState_38.Color = [0,0,1];
            
            app.Switch_39.Value = 'On';
            app.LampFunctionality_39.Color = [0,1,0];
            app.LampUseState_39.Color = [0,0,1];
            
            app.Switch_40.Value = 'On';
            app.LampFunctionality_40.Color = [0,1,0];
            app.LampUseState_40.Color = [0,0,1];

            app.Switch_41.Value = 'On';
            app.LampFunctionality_41.Color = [0,1,0];
            app.LampUseState_41.Color = [0,0,1];
            
            app.Switch_42.Value = 'On';
            app.LampFunctionality_42.Color = [0,1,0];
            app.LampUseState_42.Color = [0,0,1];
            
            app.Switch_43.Value = 'On';
            app.LampFunctionality_43.Color = [0,1,0];
            app.LampUseState_43.Color = [0,0,1];
            
            app.Switch_44.Value = 'On';
            app.LampFunctionality_44.Color = [0,1,0];
            app.LampUseState_44.Color = [0,0,1];
            
            app.Switch_45.Value = 'On';
            app.LampFunctionality_45.Color = [0,1,0];
            app.LampUseState_45.Color = [0,0,1];
            
            app.Switch_46.Value = 'On';
            app.LampFunctionality_46.Color = [0,1,0];
            app.LampUseState_46.Color = [0,0,1];
            
            app.Switch_47.Value = 'On';
            app.LampFunctionality_47.Color = [0,1,0];
            app.LampUseState_47.Color = [0,0,1];
            
            app.Switch_48.Value = 'On';
            app.LampFunctionality_48.Color = [0,1,0];
            app.LampUseState_48.Color = [0,0,1];
            
            app.Switch_49.Value = 'On';
            app.LampFunctionality_49.Color = [0,1,0];
            app.LampUseState_49.Color = [0,0,1];
            
            app.Switch_50.Value = 'On';
            app.LampFunctionality_50.Color = [0,1,0];
            app.LampUseState_50.Color = [0,0,1];

            app.Switch_51.Value = 'On';
            app.LampFunctionality_51.Color = [0,1,0];
            app.LampUseState_51.Color = [0,0,1];
            
            app.Switch_52.Value = 'On';
            app.LampFunctionality_52.Color = [0,1,0];
            app.LampUseState_52.Color = [0,0,1];
            
            app.Switch_53.Value = 'On';
            app.LampFunctionality_53.Color = [0,1,0];
            app.LampUseState_53.Color = [0,0,1];
            
            app.Switch_54.Value = 'On';
            app.LampFunctionality_54.Color = [0,1,0];
            app.LampUseState_54.Color = [0,0,1];
            
            app.Switch_55.Value = 'On';
            app.LampFunctionality_55.Color = [0,1,0];
            app.LampUseState_55.Color = [0,0,1];
            
            app.Switch_56.Value = 'On';
            app.LampFunctionality_56.Color = [0,1,0];
            app.LampUseState_56.Color = [0,0,1];
            
            app.Switch_57.Value = 'On';
            app.LampFunctionality_57.Color = [0,1,0];
            app.LampUseState_57.Color = [0,0,1];
            
            app.Switch_58.Value = 'On';
            app.LampFunctionality_58.Color = [0,1,0];
            app.LampUseState_58.Color = [0,0,1];
            
            app.Switch_59.Value = 'On';
            app.LampFunctionality_59.Color = [0,1,0];
            app.LampUseState_59.Color = [0,0,1];
            
            app.Switch_60.Value = 'On';
            app.LampFunctionality_60.Color = [0,1,0];
            app.LampUseState_60.Color = [0,0,1];

            app.Switch_61.Value = 'On';
            app.LampFunctionality_61.Color = [0,1,0];
            app.LampUseState_61.Color = [0,0,1];
            
            app.Switch_62.Value = 'On';
            app.LampFunctionality_62.Color = [0,1,0];
            app.LampUseState_62.Color = [0,0,1];
            
            app.Switch_63.Value = 'On';
            app.LampFunctionality_63.Color = [0,1,0];
            app.LampUseState_63.Color = [0,0,1];
            
            app.Switch_64.Value = 'On';
            app.LampFunctionality_64.Color = [0,1,0];
            app.LampUseState_64.Color = [0,0,1];
        end

        % Value changed function: Switch_11
        function Switch_11ValueChanged(app, event)
            switch app.Switch_11.Value
                case 'On'
                    app.LampFunctionality_11.Color = [0,1,0];
                    app.sensors(1,11) = 1;
                case 'Off'
                    app.LampFunctionality_11.Color = [1,0,0]; 
                    app.sensors(1,11) = 0;
            end
        end

        % Value changed function: Switch_12
        function Switch_12ValueChanged(app, event)
            switch app.Switch_12.Value
                case 'On'
                    app.LampFunctionality_12.Color = [0,1,0];
                    app.sensors(1,12) = 1;
                case 'Off'
                    app.LampFunctionality_12.Color = [1,0,0]; 
                    app.sensors(1,12) = 0;
            end
        end

        % Value changed function: Switch_13
        function Switch_13ValueChanged(app, event)
            switch app.Switch_13.Value
                case 'On'
                    app.LampFunctionality_13.Color = [0,1,0];
                    app.sensors(1,13) = 1;
                case 'Off'
                    app.LampFunctionality_13.Color = [1,0,0]; 
                    app.sensors(1,13) = 0;
            end
        end

        % Value changed function: Switch_14
        function Switch_14ValueChanged(app, event)
            switch app.Switch_14.Value
                case 'On'
                    app.LampFunctionality_14.Color = [0,1,0];
                    app.sensors(1,14) = 1;
                case 'Off'
                    app.LampFunctionality_14.Color = [1,0,0]; 
                    app.sensors(1,14) = 0;
            end
        end

        % Value changed function: Switch_15
        function Switch_15ValueChanged(app, event)
            switch app.Switch_15.Value
                case 'On'
                    app.LampFunctionality_15.Color = [0,1,0];
                    app.sensors(1,15) = 1;
                case 'Off'
                    app.LampFunctionality_15.Color = [1,0,0]; 
                    app.sensors(1,15) = 0;
            end
        end

        % Value changed function: Switch_16
        function Switch_16ValueChanged(app, event)
            switch app.Switch_16.Value
                case 'On'
                    app.LampFunctionality_16.Color = [0,1,0];
                    app.sensors(1,16) = 1;
                case 'Off'
                    app.LampFunctionality_16.Color = [1,0,0]; 
                    app.sensors(1,16) = 0;
            end
        end

        % Value changed function: Switch_17
        function Switch_17ValueChanged(app, event)
            switch app.Switch_17.Value
                case 'On'
                    app.LampFunctionality_17.Color = [0,1,0];
                    app.sensors(1,17) = 1;
                case 'Off'
                    app.LampFunctionality_17.Color = [1,0,0]; 
                    app.sensors(1,17) = 0;
            end
        end

        % Value changed function: Switch_18
        function Switch_18ValueChanged(app, event)
            switch app.Switch_18.Value
                case 'On'
                    app.LampFunctionality_18.Color = [0,1,0];
                    app.sensors(1,18) = 1;
                case 'Off'
                    app.LampFunctionality_18.Color = [1,0,0]; 
                    app.sensors(1,18) = 0;
            end
        end

        % Value changed function: Switch_19
        function Switch_19ValueChanged(app, event)
            switch app.Switch_19.Value
                case 'On'
                    app.LampFunctionality_19.Color = [0,1,0];
                    app.sensors(1,19) = 1;
                case 'Off'
                    app.LampFunctionality_19.Color = [1,0,0]; 
                    app.sensors(1,19) = 0;
            end
        end

        % Value changed function: Switch_20
        function Switch_20ValueChanged(app, event)
            switch app.Switch_20.Value
                case 'On'
                    app.LampFunctionality_20.Color = [0,1,0];
                    app.sensors(1,20) = 1;
                case 'Off'
                    app.LampFunctionality_20.Color = [1,0,0]; 
                    app.sensors(1,20) = 0;
            end
        end

        % Value changed function: Switch_21
        function Switch_21ValueChanged(app, event)
            switch app.Switch_21.Value
                case 'On'
                    app.LampFunctionality_21.Color = [0,1,0];
                    app.sensors(1,21) = 1;
                case 'Off'
                    app.LampFunctionality_21.Color = [1,0,0]; 
                    app.sensors(1,21) = 0;
            end
        end

        % Value changed function: Switch_22
        function Switch_22ValueChanged(app, event)
            switch app.Switch_22.Value
                case 'On'
                    app.LampFunctionality_22.Color = [0,1,0];
                    app.sensors(1,22) = 1;
                case 'Off'
                    app.LampFunctionality_22.Color = [1,0,0]; 
                    app.sensors(1,22) = 0;
            end
        end

        % Value changed function: Switch_23
        function Switch_23ValueChanged(app, event)
            switch app.Switch_23.Value
                case 'On'
                    app.LampFunctionality_23.Color = [0,1,0];
                    app.sensors(1,23) = 1;
                case 'Off'
                    app.LampFunctionality_23.Color = [1,0,0]; 
                    app.sensors(1,23) = 0;
            end
        end

        % Value changed function: Switch_24
        function Switch_24ValueChanged(app, event)
            switch app.Switch_24.Value
                case 'On'
                    app.LampFunctionality_24.Color = [0,1,0];
                    app.sensors(1,24) = 1;
                case 'Off'
                    app.LampFunctionality_24.Color = [1,0,0]; 
                    app.sensors(1,24) = 0;
            end
        end

        % Value changed function: Switch_25
        function Switch_25ValueChanged(app, event)
            switch app.Switch_25.Value
                case 'On'
                    app.LampFunctionality_25.Color = [0,1,0];
                    app.sensors(1,25) = 1;
                case 'Off'
                    app.LampFunctionality_25.Color = [1,0,0]; 
                    app.sensors(1,25) = 0;
            end
        end

        % Value changed function: Switch_26
        function Switch_26ValueChanged(app, event)
            switch app.Switch_26.Value
                case 'On'
                    app.LampFunctionality_26.Color = [0,1,0];
                    app.sensors(1,26) = 1;
                case 'Off'
                    app.LampFunctionality_26.Color = [1,0,0]; 
                    app.sensors(1,26) = 0;
            end
        end

        % Value changed function: Switch_27
        function Switch_27ValueChanged(app, event)
            switch app.Switch_27.Value
                case 'On'
                    app.LampFunctionality_27.Color = [0,1,0];
                    app.sensors(1,27) = 1;
                case 'Off'
                    app.LampFunctionality_27.Color = [1,0,0]; 
                    app.sensors(1,27) = 0;
            end
        end

        % Value changed function: Switch_28
        function Switch_28ValueChanged(app, event)
            switch app.Switch_28.Value
                case 'On'
                    app.LampFunctionality_28.Color = [0,1,0];
                    app.sensors(1,28) = 1;
                case 'Off'
                    app.LampFunctionality_28.Color = [1,0,0]; 
                    app.sensors(1,28) = 0;
            end
        end

        % Value changed function: Switch_29
        function Switch_29ValueChanged(app, event)
            switch app.Switch_29.Value
                case 'On'
                    app.LampFunctionality_29.Color = [0,1,0];
                    app.sensors(1,29) = 1;
                case 'Off'
                    app.LampFunctionality_29.Color = [1,0,0]; 
                    app.sensors(1,29) = 0;
            end
        end

        % Value changed function: Switch_30
        function Switch_30ValueChanged(app, event)
            switch app.Switch_30.Value
                case 'On'
                    app.LampFunctionality_30.Color = [0,1,0];
                    app.sensors(1,30) = 1;
                case 'Off'
                    app.LampFunctionality_30.Color = [1,0,0]; 
                    app.sensors(1,30) = 0;
            end
        end

        % Value changed function: Switch_31
        function Switch_31ValueChanged(app, event)
            switch app.Switch_31.Value
                case 'On'
                    app.LampFunctionality_31.Color = [0,1,0];
                    app.sensors(1,31) = 1;
                case 'Off'
                    app.LampFunctionality_31.Color = [1,0,0]; 
                    app.sensors(1,31) = 0;
            end
        end

        % Value changed function: Switch_32
        function Switch_32ValueChanged(app, event)
            switch app.Switch_32.Value
                case 'On'
                    app.LampFunctionality_32.Color = [0,1,0];
                    app.sensors(1,32) = 1;
                case 'Off'
                    app.LampFunctionality_32.Color = [1,0,0]; 
                    app.sensors(1,32) = 0;
            end
        end

        % Value changed function: Switch_33
        function Switch_33ValueChanged(app, event)
            switch app.Switch_33.Value
                case 'On'
                    app.LampFunctionality_33.Color = [0,1,0];
                    app.sensors(1,33) = 1;
                case 'Off'
                    app.LampFunctionality_33.Color = [1,0,0]; 
                    app.sensors(1,33) = 0;
            end
        end

        % Value changed function: Switch_34
        function Switch_34ValueChanged(app, event)
            switch app.Switch_34.Value
                case 'On'
                    app.LampFunctionality_34.Color = [0,1,0];
                    app.sensors(1,34) = 1;
                case 'Off'
                    app.LampFunctionality_34.Color = [1,0,0]; 
                    app.sensors(1,34) = 0;
            end
        end

        % Value changed function: Switch_35
        function Switch_35ValueChanged(app, event)
            switch app.Switch_35.Value
                case 'On'
                    app.LampFunctionality_35.Color = [0,1,0];
                    app.sensors(1,35) = 1;
                case 'Off'
                    app.LampFunctionality_35.Color = [1,0,0]; 
                    app.sensors(1,35) = 0;
            end
        end

        % Value changed function: Switch_36
        function Switch_36ValueChanged(app, event)
            switch app.Switch_36.Value
                case 'On'
                    app.LampFunctionality_36.Color = [0,1,0];
                    app.sensors(1,36) = 1;
                case 'Off'
                    app.LampFunctionality_36.Color = [1,0,0]; 
                    app.sensors(1,36) = 0;
            end
        end

        % Value changed function: Switch_37
        function Switch_37ValueChanged(app, event)
            switch app.Switch_37.Value
                case 'On'
                    app.LampFunctionality_37.Color = [0,1,0];
                    app.sensors(1,37) = 1;
                case 'Off'
                    app.LampFunctionality_37.Color = [1,0,0]; 
                    app.sensors(1,37) = 0;
            end
        end

        % Value changed function: Switch_38
        function Switch_38ValueChanged(app, event)
            switch app.Switch_38.Value
                case 'On'
                    app.LampFunctionality_38.Color = [0,1,0];
                    app.sensors(1,38) = 1;
                case 'Off'
                    app.LampFunctionality_38.Color = [1,0,0]; 
                    app.sensors(1,38) = 0;
            end
        end

        % Value changed function: Switch_39
        function Switch_39ValueChanged(app, event)
            switch app.Switch_39.Value
                case 'On'
                    app.LampFunctionality_39.Color = [0,1,0];
                    app.sensors(1,39) = 1;
                case 'Off'
                    app.LampFunctionality_39.Color = [1,0,0]; 
                    app.sensors(1,39) = 0;
            end
        end

        % Value changed function: Switch_40
        function Switch_40ValueChanged(app, event)
            switch app.Switch_40.Value
                case 'On'
                    app.LampFunctionality_40.Color = [0,1,0];
                    app.sensors(1,40) = 1;
                case 'Off'
                    app.LampFunctionality_40.Color = [1,0,0]; 
                    app.sensors(1,40) = 0;
            end
        end

        % Value changed function: Switch_41
        function Switch_41ValueChanged(app, event)
            switch app.Switch_41.Value
                case 'On'
                    app.LampFunctionality_41.Color = [0,1,0];
                    app.sensors(1,41) = 1;
                case 'Off'
                    app.LampFunctionality_41.Color = [1,0,0]; 
                    app.sensors(1,41) = 0;
            end
        end

        % Value changed function: Switch_42
        function Switch_42ValueChanged(app, event)
            switch app.Switch_42.Value
                case 'On'
                    app.LampFunctionality_42.Color = [0,1,0];
                    app.sensors(1,42) = 1;
                case 'Off'
                    app.LampFunctionality_42.Color = [1,0,0]; 
                    app.sensors(1,42) = 0;
            end
        end

        % Value changed function: Switch_43
        function Switch_43ValueChanged(app, event)
            switch app.Switch_43.Value
                case 'On'
                    app.LampFunctionality_43.Color = [0,1,0];
                    app.sensors(1,43) = 1;
                case 'Off'
                    app.LampFunctionality_43.Color = [1,0,0]; 
                    app.sensors(1,43) = 0;
            end
        end

        % Value changed function: Switch_44
        function Switch_44ValueChanged(app, event)
            switch app.Switch_44.Value
                case 'On'
                    app.LampFunctionality_44.Color = [0,1,0];
                    app.sensors(1,44) = 1;
                case 'Off'
                    app.LampFunctionality_44.Color = [1,0,0]; 
                    app.sensors(1,44) = 0;
            end
        end

        % Value changed function: Switch_45
        function Switch_45ValueChanged(app, event)
            switch app.Switch_45.Value
                case 'On'
                    app.LampFunctionality_45.Color = [0,1,0];
                    app.sensors(1,45) = 1;
                case 'Off'
                    app.LampFunctionality_45.Color = [1,0,0]; 
                    app.sensors(1,45) = 0;
            end
        end

        % Value changed function: Switch_46
        function Switch_46ValueChanged(app, event)
            switch app.Switch_46.Value
                case 'On'
                    app.LampFunctionality_46.Color = [0,1,0];
                    app.sensors(1,46) = 1;
                case 'Off'
                    app.LampFunctionality_46.Color = [1,0,0]; 
                    app.sensors(1,46) = 0;
            end
        end

        % Value changed function: Switch_47
        function Switch_47ValueChanged(app, event)
            switch app.Switch_47.Value
                case 'On'
                    app.LampFunctionality_47.Color = [0,1,0];
                    app.sensors(1,47) = 1;
                case 'Off'
                    app.LampFunctionality_47.Color = [1,0,0]; 
                    app.sensors(1,47) = 0;
            end
        end

        % Value changed function: Switch_48
        function Switch_48ValueChanged(app, event)
            switch app.Switch_48.Value
                case 'On'
                    app.LampFunctionality_48.Color = [0,1,0];
                    app.sensors(1,48) = 1;
                case 'Off'
                    app.LampFunctionality_48.Color = [1,0,0]; 
                    app.sensors(1,48) = 0;
            end
        end

        % Value changed function: Switch_49
        function Switch_49ValueChanged(app, event)
            switch app.Switch_49.Value
                case 'On'
                    app.LampFunctionality_49.Color = [0,1,0];
                    app.sensors(1,49) = 1;
                case 'Off'
                    app.LampFunctionality_49.Color = [1,0,0]; 
                    app.sensors(1,49) = 0;
            end
        end

        % Value changed function: Switch_50
        function Switch_50ValueChanged(app, event)
            switch app.Switch_50.Value
                case 'On'
                    app.LampFunctionality_50.Color = [0,1,0];
                    app.sensors(1,50) = 1;
                case 'Off'
                    app.LampFunctionality_50.Color = [1,0,0]; 
                    app.sensors(1,50) = 0;
            end
        end

        % Value changed function: Switch_51
        function Switch_51ValueChanged(app, event)
            switch app.Switch_51.Value
                case 'On'
                    app.LampFunctionality_51.Color = [0,1,0];
                    app.sensors(1,51) = 1;
                case 'Off'
                    app.LampFunctionality_51.Color = [1,0,0]; 
                    app.sensors(1,51) = 0;
            end
        end

        % Value changed function: Switch_52
        function Switch_52ValueChanged(app, event)
            switch app.Switch_52.Value
                case 'On'
                    app.LampFunctionality_52.Color = [0,1,0];
                    app.sensors(1,52) = 1;
                case 'Off'
                    app.LampFunctionality_52.Color = [1,0,0]; 
                    app.sensors(1,52) = 0;
            end
        end

        % Value changed function: Switch_53
        function Switch_53ValueChanged(app, event)
            switch app.Switch_53.Value
                case 'On'
                    app.LampFunctionality_53.Color = [0,1,0];
                    app.sensors(1,53) = 1;
                case 'Off'
                    app.LampFunctionality_53.Color = [1,0,0]; 
                    app.sensors(1,53) = 0;
            end
        end

        % Value changed function: Switch_54
        function Switch_54ValueChanged(app, event)
            switch app.Switch_54.Value
                case 'On'
                    app.LampFunctionality_54.Color = [0,1,0];
                    app.sensors(1,54) = 1;
                case 'Off'
                    app.LampFunctionality_54.Color = [1,0,0]; 
                    app.sensors(1,54) = 0;
            end
        end

        % Value changed function: Switch_55
        function Switch_55ValueChanged(app, event)
            switch app.Switch_55.Value
                case 'On'
                    app.LampFunctionality_55.Color = [0,1,0];
                    app.sensors(1,55) = 1;
                case 'Off'
                    app.LampFunctionality_55.Color = [1,0,0]; 
                    app.sensors(1,55) = 0;
            end
        end

        % Value changed function: Switch_56
        function Switch_56ValueChanged(app, event)
            switch app.Switch_56.Value
                case 'On'
                    app.LampFunctionality_56.Color = [0,1,0];
                    app.sensors(1,56) = 1;
                case 'Off'
                    app.LampFunctionality_56.Color = [1,0,0]; 
                    app.sensors(1,56) = 0;
            end
        end

        % Value changed function: Switch_57
        function Switch_57ValueChanged(app, event)
            switch app.Switch_57.Value
                case 'On'
                    app.LampFunctionality_57.Color = [0,1,0];
                    app.sensors(1,57) = 1;
                case 'Off'
                    app.LampFunctionality_57.Color = [1,0,0]; 
                    app.sensors(1,57) = 0;
            end
        end

        % Value changed function: Switch_58
        function Switch_58ValueChanged(app, event)
            switch app.Switch_58.Value
                case 'On'
                    app.LampFunctionality_58.Color = [0,1,0];
                    app.sensors(1,58) = 1;
                case 'Off'
                    app.LampFunctionality_58.Color = [1,0,0]; 
                    app.sensors(1,58) = 0;
            end
        end

        % Value changed function: Switch_59
        function Switch_59ValueChanged(app, event)
            switch app.Switch_59.Value
                case 'On'
                    app.LampFunctionality_59.Color = [0,1,0];
                    app.sensors(1,59) = 1;
                case 'Off'
                    app.LampFunctionality_59.Color = [1,0,0]; 
                    app.sensors(1,59) = 0;
            end
        end

        % Value changed function: Switch_60
        function Switch_60ValueChanged(app, event)
            switch app.Switch_60.Value
                case 'On'
                    app.LampFunctionality_60.Color = [0,1,0];
                    app.sensors(1,60) = 1;
                case 'Off'
                    app.LampFunctionality_60.Color = [1,0,0]; 
                    app.sensors(1,60) = 0;
            end
        end

        % Value changed function: Switch_61
        function Switch_61ValueChanged(app, event)
            switch app.Switch_61.Value
                case 'On'
                    app.LampFunctionality_61.Color = [0,1,0];
                    app.sensors(1,61) = 1;
                case 'Off'
                    app.LampFunctionality_61.Color = [1,0,0]; 
                    app.sensors(1,61) = 0;
            end
        end

        % Value changed function: Switch_62
        function Switch_62ValueChanged(app, event)
            switch app.Switch_62.Value
                case 'On'
                    app.LampFunctionality_62.Color = [0,1,0];
                    app.sensors(1,62) = 1;
                case 'Off'
                    app.LampFunctionality_62.Color = [1,0,0]; 
                    app.sensors(1,62) = 0;
            end
        end

        % Value changed function: Switch_63
        function Switch_63ValueChanged(app, event)
            switch app.Switch_63.Value
                case 'On'
                    app.LampFunctionality_63.Color = [0,1,0];
                    app.sensors(1,63) = 1;
                case 'Off'
                    app.LampFunctionality_63.Color = [1,0,0]; 
                    app.sensors(1,63) = 0;
            end
        end

        % Value changed function: Switch_64
        function Switch_64ValueChanged(app, event)
            switch app.Switch_64.Value
                case 'On'
                    app.LampFunctionality_64.Color = [0,1,0];
                    app.sensors(1,64) = 1;
                case 'Off'
                    app.LampFunctionality_64.Color = [1,0,0]; 
                    app.sensors(1,64) = 0;
            end
        end

        % Button pushed function: FailAllSensorsButton
        function FailAllSensorsButtonPushed(app, event)
            app.sensors = zeros(1,64);
            
            app.Switch_1.Value = 'Off';
            app.LampFunctionality_1.Color = [1,0,0];
            
            app.Switch_2.Value = 'Off';
            app.LampFunctionality_2.Color = [1,0,0];
            
            app.Switch_3.Value = 'Off';
            app.LampFunctionality_3.Color = [1,0,0];
            
            app.Switch_4.Value = 'Off';
            app.LampFunctionality_4.Color = [1,0,0];
            
            app.Switch_5.Value = 'Off';
            app.LampFunctionality_5.Color = [1,0,0];
            
            app.Switch_6.Value = 'Off';
            app.LampFunctionality_6.Color = [1,0,0];
            
            app.Switch_7.Value = 'Off';
            app.LampFunctionality_7.Color = [1,0,0];
            
            app.Switch_8.Value = 'Off';
            app.LampFunctionality_8.Color = [1,0,0];
            
            app.Switch_9.Value = 'Off';
            app.LampFunctionality_9.Color = [1,0,0];
            
            app.Switch_10.Value = 'Off';
            app.LampFunctionality_10.Color = [1,0,0];

            app.Switch_11.Value = 'Off';
            app.LampFunctionality_11.Color = [1,0,0];
            
            app.Switch_12.Value = 'Off';
            app.LampFunctionality_12.Color = [1,0,0];
            
            app.Switch_13.Value = 'Off';
            app.LampFunctionality_13.Color = [1,0,0];
            
            app.Switch_14.Value = 'Off';
            app.LampFunctionality_14.Color = [1,0,0];
            
            app.Switch_15.Value = 'Off';
            app.LampFunctionality_15.Color = [1,0,0];
            
            app.Switch_16.Value = 'Off';
            app.LampFunctionality_16.Color = [1,0,0];
            
            app.Switch_17.Value = 'Off';
            app.LampFunctionality_17.Color = [1,0,0];
            
            app.Switch_18.Value = 'Off';
            app.LampFunctionality_18.Color = [1,0,0];
            
            app.Switch_19.Value = 'Off';
            app.LampFunctionality_19.Color = [1,0,0];
            
            app.Switch_20.Value = 'Off';
            app.LampFunctionality_20.Color = [1,0,0];

            app.Switch_21.Value = 'Off';
            app.LampFunctionality_21.Color = [1,0,0];
            
            app.Switch_22.Value = 'Off';
            app.LampFunctionality_22.Color = [1,0,0];
            
            app.Switch_23.Value = 'Off';
            app.LampFunctionality_23.Color = [1,0,0];
            
            app.Switch_24.Value = 'Off';
            app.LampFunctionality_24.Color = [1,0,0];
            
            app.Switch_25.Value = 'Off';
            app.LampFunctionality_25.Color = [1,0,0];
            
            app.Switch_26.Value = 'Off';
            app.LampFunctionality_26.Color = [1,0,0];
            
            app.Switch_27.Value = 'Off';
            app.LampFunctionality_27.Color = [1,0,0];
            
            app.Switch_28.Value = 'Off';
            app.LampFunctionality_28.Color = [1,0,0];
            
            app.Switch_29.Value = 'Off';
            app.LampFunctionality_29.Color = [1,0,0];
            
            app.Switch_30.Value = 'Off';
            app.LampFunctionality_30.Color = [1,0,0];

            app.Switch_31.Value = 'Off';
            app.LampFunctionality_31.Color = [1,0,0];
            
            app.Switch_32.Value = 'Off';
            app.LampFunctionality_32.Color = [1,0,0];
            
            app.Switch_33.Value = 'Off';
            app.LampFunctionality_33.Color = [1,0,0];
            
            app.Switch_34.Value = 'Off';
            app.LampFunctionality_34.Color = [1,0,0];
            
            app.Switch_35.Value = 'Off';
            app.LampFunctionality_35.Color = [1,0,0];
            
            app.Switch_36.Value = 'Off';
            app.LampFunctionality_36.Color = [1,0,0];
            
            app.Switch_37.Value = 'Off';
            app.LampFunctionality_37.Color = [1,0,0];
            
            app.Switch_38.Value = 'Off';
            app.LampFunctionality_38.Color = [1,0,0];
            
            app.Switch_39.Value = 'Off';
            app.LampFunctionality_39.Color = [1,0,0];
            
            app.Switch_40.Value = 'Off';
            app.LampFunctionality_40.Color = [1,0,0];

            app.Switch_41.Value = 'Off';
            app.LampFunctionality_41.Color = [1,0,0];
            
            app.Switch_42.Value = 'Off';
            app.LampFunctionality_42.Color = [1,0,0];
            
            app.Switch_43.Value = 'Off';
            app.LampFunctionality_43.Color = [1,0,0];
            
            app.Switch_44.Value = 'Off';
            app.LampFunctionality_44.Color = [1,0,0];
            
            app.Switch_45.Value = 'Off';
            app.LampFunctionality_45.Color = [1,0,0];
            
            app.Switch_46.Value = 'Off';
            app.LampFunctionality_46.Color = [1,0,0];
            
            app.Switch_47.Value = 'Off';
            app.LampFunctionality_47.Color = [1,0,0];
            
            app.Switch_48.Value = 'Off';
            app.LampFunctionality_48.Color = [1,0,0];
            
            app.Switch_49.Value = 'Off';
            app.LampFunctionality_49.Color = [1,0,0];
            
            app.Switch_50.Value = 'Off';
            app.LampFunctionality_50.Color = [1,0,0];

            app.Switch_51.Value = 'Off';
            app.LampFunctionality_51.Color = [1,0,0];
            
            app.Switch_52.Value = 'Off';
            app.LampFunctionality_52.Color = [1,0,0];
            
            app.Switch_53.Value = 'Off';
            app.LampFunctionality_53.Color = [1,0,0];
            
            app.Switch_54.Value = 'Off';
            app.LampFunctionality_54.Color = [1,0,0];
            
            app.Switch_55.Value = 'Off';
            app.LampFunctionality_55.Color = [1,0,0];
            
            app.Switch_56.Value = 'Off';
            app.LampFunctionality_56.Color = [1,0,0];
            
            app.Switch_57.Value = 'Off';
            app.LampFunctionality_57.Color = [1,0,0];
            
            app.Switch_58.Value = 'Off';
            app.LampFunctionality_58.Color = [1,0,0];

            app.Switch_59.Value = 'Off';
            app.LampFunctionality_59.Color = [1,0,0];

            app.Switch_60.Value = 'Off';
            app.LampFunctionality_60.Color = [1,0,0];

            app.Switch_61.Value = 'Off';
            app.LampFunctionality_61.Color = [1,0,0];
            
            app.Switch_62.Value = 'Off';
            app.LampFunctionality_62.Color = [1,0,0];

            app.Switch_63.Value = 'Off';
            app.LampFunctionality_63.Color = [1,0,0];

            app.Switch_64.Value = 'Off';
            app.LampFunctionality_64.Color = [1,0,0];

        end

        % Button pushed function: NotanNSAButton
        function NotanNSAButtonPushed(app, event)
            app.sensors = zeros(1,64);
            
            app.Switch_1.Value = 'Off';
            app.LampFunctionality_1.Color = [1,0,0];
            
            app.Switch_2.Value = 'Off';
            app.LampFunctionality_2.Color = [1,0,0];
            
            app.Switch_3.Value = 'On';
            app.LampFunctionality_3.Color = [0,1,0];
            app.sensors(3) = 1;
            
            app.Switch_4.Value = 'Off';
            app.LampFunctionality_4.Color = [1,0,0];
            
            app.Switch_5.Value = 'Off';
            app.LampFunctionality_5.Color = [1,0,0];
            
            app.Switch_6.Value = 'On';
            app.LampFunctionality_6.Color = [0,1,0];
            app.sensors(6) = 1;
            
            app.Switch_7.Value = 'On';
            app.LampFunctionality_7.Color = [0,1,0];
            app.sensors(7) = 1;
            
            app.Switch_8.Value = 'Off';
            app.LampFunctionality_8.Color = [1,0,0];
            
            app.Switch_9.Value = 'Off';
            app.LampFunctionality_9.Color = [1,0,0];
            
            app.Switch_10.Value = 'Off';
            app.LampFunctionality_10.Color = [1,0,0];

            app.Switch_11.Value = 'Off';
            app.LampFunctionality_11.Color = [1,0,0];
            
            app.Switch_12.Value = 'On';
            app.LampFunctionality_12.Color = [0,1,0];
            app.sensors(12) = 1;
            
            app.Switch_13.Value = 'On';
            app.LampFunctionality_13.Color = [0,1,0];
            app.sensors(13) = 1;
            
            app.Switch_14.Value = 'Off';
            app.LampFunctionality_14.Color = [1,0,0];
            
            app.Switch_15.Value = 'Off';
            app.LampFunctionality_15.Color = [1,0,0];
            
            app.Switch_16.Value = 'On';
            app.LampFunctionality_16.Color = [0,1,0];
            app.sensors(16) = 1;
            
            app.Switch_17.Value = 'Off';
            app.LampFunctionality_17.Color = [1,0,0];
            
            app.Switch_18.Value = 'Off';
            app.LampFunctionality_18.Color = [1,0,0];
            
            app.Switch_19.Value = 'Off';
            app.LampFunctionality_19.Color = [1,0,0];
            
            app.Switch_20.Value = 'Off';
            app.LampFunctionality_20.Color = [1,0,0];

            app.Switch_21.Value = 'On';
            app.LampFunctionality_21.Color = [0,1,0];
            app.sensors(21) = 1;
            
            app.Switch_22.Value = 'Off';
            app.LampFunctionality_22.Color = [1,0,0];
            
            app.Switch_23.Value = 'Off';
            app.LampFunctionality_23.Color = [1,0,0];
            
            app.Switch_24.Value = 'Off';
            app.LampFunctionality_24.Color = [1,0,0];
            
            app.Switch_25.Value = 'Off';
            app.LampFunctionality_25.Color = [1,0,0];
            
            app.Switch_26.Value = 'Off';
            app.LampFunctionality_26.Color = [1,0,0];
            
            app.Switch_27.Value = 'Off';
            app.LampFunctionality_27.Color = [1,0,0];
            
            app.Switch_28.Value = 'Off';
            app.LampFunctionality_28.Color = [1,0,0];
            
            app.Switch_29.Value = 'Off';
            app.LampFunctionality_29.Color = [1,0,0];
            
            app.Switch_30.Value = 'Off';
            app.LampFunctionality_30.Color = [1,0,0];

            app.Switch_31.Value = 'Off';
            app.LampFunctionality_31.Color = [1,0,0];
            
            app.Switch_32.Value = 'Off';
            app.LampFunctionality_32.Color = [1,0,0];
            
            app.Switch_33.Value = 'Off';
            app.LampFunctionality_33.Color = [1,0,0];
            
            app.Switch_34.Value = 'Off';
            app.LampFunctionality_34.Color = [1,0,0];
            
            app.Switch_35.Value = 'Off';
            app.LampFunctionality_35.Color = [1,0,0];
            
            app.Switch_36.Value = 'Off';
            app.LampFunctionality_36.Color = [1,0,0];
            
            app.Switch_37.Value = 'On';
            app.LampFunctionality_37.Color = [0,1,0];
            app.sensors(37) = 1;
            
            app.Switch_38.Value = 'Off';
            app.LampFunctionality_38.Color = [1,0,0];
            
            app.Switch_39.Value = 'Off';
            app.LampFunctionality_39.Color = [1,0,0];
            
            app.Switch_40.Value = 'Off';
            app.LampFunctionality_40.Color = [1,0,0];

            app.Switch_41.Value = 'Off';
            app.LampFunctionality_41.Color = [1,0,0];
            
            app.Switch_42.Value = 'Off';
            app.LampFunctionality_42.Color = [1,0,0];
            
            app.Switch_43.Value = 'Off';
            app.LampFunctionality_43.Color = [1,0,0];
            
            app.Switch_44.Value = 'Off';
            app.LampFunctionality_44.Color = [1,0,0];
            
            app.Switch_45.Value = 'Off';
            app.LampFunctionality_45.Color = [1,0,0];
            
            app.Switch_46.Value = 'On';
            app.LampFunctionality_46.Color = [0,1,0];
            app.sensors(46) = 1;
            
            app.Switch_47.Value = 'Off';
            app.LampFunctionality_47.Color = [1,0,0];
            
            app.Switch_48.Value = 'Off';
            app.LampFunctionality_48.Color = [1,0,0];
            
            app.Switch_49.Value = 'Off';
            app.LampFunctionality_49.Color = [1,0,0];
            
            app.Switch_50.Value = 'Off';
            app.LampFunctionality_50.Color = [1,0,0];

            app.Switch_51.Value = 'Off';
            app.LampFunctionality_51.Color = [1,0,0];
            
            app.Switch_52.Value = 'Off';
            app.LampFunctionality_52.Color = [1,0,0];
            
            app.Switch_53.Value = 'On';
            app.LampFunctionality_53.Color = [0,1,0];
            app.sensors(53) = 1;
            
            app.Switch_54.Value = 'On';
            app.LampFunctionality_54.Color = [0,1,0];
            app.sensors(54) = 1;
            
            app.Switch_55.Value = 'Off';
            app.LampFunctionality_55.Color = [1,0,0];
            
            app.Switch_56.Value = 'Off';
            app.LampFunctionality_56.Color = [1,0,0];
            
            app.Switch_57.Value = 'Off';
            app.LampFunctionality_57.Color = [1,0,0];
            
            app.Switch_58.Value = 'Off';
            app.LampFunctionality_58.Color = [1,0,0];

            app.Switch_59.Value = 'Off';
            app.LampFunctionality_59.Color = [1,0,0];

            app.Switch_60.Value = 'On';
            app.LampFunctionality_60.Color = [0,1,0];
            app.sensors(60) = 1;

            app.Switch_61.Value = 'Off';
            app.LampFunctionality_61.Color = [1,0,0];
            
            app.Switch_62.Value = 'Off';
            app.LampFunctionality_62.Color = [1,0,0];

            app.Switch_63.Value = 'On';
            app.LampFunctionality_63.Color = [0,1,0];
            app.sensors(63) = 1;

            app.Switch_64.Value = 'Off';
            app.LampFunctionality_64.Color = [0,1,0];
        end

        % Button pushed function: NSAbadPSLButton
        function NSAbadPSLButtonPushed(app, event)
            app.sensors = zeros(1,64);
            
            app.Switch_1.Value = 'Off';
            app.LampFunctionality_1.Color = [1,0,0];
            
            app.Switch_2.Value = 'Off';
            app.LampFunctionality_2.Color = [1,0,0];
            
            app.Switch_3.Value = 'Off';
            app.LampFunctionality_3.Color = [1,0,0];
            
            app.Switch_4.Value = 'On';
            app.LampFunctionality_4.Color = [0,1,0];
            app.sensors(4) = 1;
            
            app.Switch_5.Value = 'On';
            app.LampFunctionality_5.Color = [0,1,0];
            app.sensors(5) = 1;
            
            app.Switch_6.Value = 'On';
            app.LampFunctionality_6.Color = [0,1,0];
            app.sensors(6) = 1;
            
            app.Switch_7.Value = 'On';
            app.LampFunctionality_7.Color = [0,1,0];
            app.sensors(7) = 1;
            
            app.Switch_8.Value = 'Off';
            app.LampFunctionality_8.Color = [1,0,0];
            
            app.Switch_9.Value = 'On';
            app.LampFunctionality_9.Color = [0,1,0];
            app.sensors(9) = 1;
            
            app.Switch_10.Value = 'Off';
            app.LampFunctionality_10.Color = [1,0,0];

            app.Switch_11.Value = 'Off';
            app.LampFunctionality_11.Color = [1,0,0];
            
            app.Switch_12.Value = 'Off';
            app.LampFunctionality_12.Color = [1,0,0];
            
            app.Switch_13.Value = 'Off';
            app.LampFunctionality_13.Color = [1,0,0];
            
            app.Switch_14.Value = 'On';
            app.LampFunctionality_14.Color = [0,1,0];
            app.sensors(14) = 1;
            
            app.Switch_15.Value = 'Off';
            app.LampFunctionality_15.Color = [1,0,0];
            
            app.Switch_16.Value = 'Off';
            app.LampFunctionality_16.Color = [1,0,0];
            
            app.Switch_17.Value = 'Off';
            app.LampFunctionality_17.Color = [0,1,0];
            app.sensors(17) = 1;
            
            app.Switch_18.Value = 'Off';
            app.LampFunctionality_18.Color = [1,0,0];
            
            app.Switch_19.Value = 'Off';
            app.LampFunctionality_19.Color = [1,0,0];
            
            app.Switch_20.Value = 'Off';
            app.LampFunctionality_20.Color = [1,0,0];

            app.Switch_21.Value = 'Off';
            app.LampFunctionality_21.Color = [1,0,0];
            
            app.Switch_22.Value = 'Off';
            app.LampFunctionality_22.Color = [1,0,0];
            
            app.Switch_23.Value = 'On';
            app.LampFunctionality_23.Color = [0,1,0];
            app.sensors(23) = 1;
            
            app.Switch_24.Value = 'Off';
            app.LampFunctionality_24.Color = [1,0,0];
            
            app.Switch_25.Value = 'Off';
            app.LampFunctionality_25.Color = [1,0,0];
            
            app.Switch_26.Value = 'On';
            app.LampFunctionality_26.Color = [0,1,0];
            app.sensors(26) = 1;
            
            app.Switch_27.Value = 'Off';
            app.LampFunctionality_27.Color = [1,0,0];
            
            app.Switch_28.Value = 'Off';
            app.LampFunctionality_28.Color = [1,0,0];
            
            app.Switch_29.Value = 'Off';
            app.LampFunctionality_29.Color = [1,0,0];
            
            app.Switch_30.Value = 'Off';
            app.LampFunctionality_30.Color = [1,0,0];

            app.Switch_31.Value = 'On';
            app.LampFunctionality_31.Color = [0,1,0];
            app.sensors(31) = 1;
            
            app.Switch_32.Value = 'Off';
            app.LampFunctionality_32.Color = [1,0,0];
            
            app.Switch_33.Value = 'Off';
            app.LampFunctionality_33.Color = [1,0,0];
            
            app.Switch_34.Value = 'Off';
            app.LampFunctionality_34.Color = [1,0,0];
            
            app.Switch_35.Value = 'Off';
            app.LampFunctionality_35.Color = [1,0,0];
            
            app.Switch_36.Value = 'On';
            app.LampFunctionality_36.Color = [0,1,0];
            app.sensors(36) = 1;
            
            app.Switch_37.Value = 'Off';
            app.LampFunctionality_37.Color = [1,0,0];
            
            app.Switch_38.Value = 'Off';
            app.LampFunctionality_38.Color = [1,0,0];
            
            app.Switch_39.Value = 'On';
            app.LampFunctionality_39.Color = [0,1,0];
            app.sensors(39) = 1;
            
            app.Switch_40.Value = 'On';
            app.LampFunctionality_40.Color = [0,1,0];
            app.sensors(40) = 1;

            app.Switch_41.Value = 'Off';
            app.LampFunctionality_41.Color = [1,0,0];
            
            app.Switch_42.Value = 'Off';
            app.LampFunctionality_42.Color = [1,0,0];
            
            app.Switch_43.Value = 'On';
            app.LampFunctionality_43.Color = [0,1,0];
            app.sensors(43) = 1;
            
            app.Switch_44.Value = 'Off';
            app.LampFunctionality_44.Color = [1,0,0];
            
            app.Switch_45.Value = 'Off';
            app.LampFunctionality_45.Color = [1,0,0];
            
            app.Switch_46.Value = 'Off';
            app.LampFunctionality_46.Color = [1,0,0];
            
            app.Switch_47.Value = 'Off';
            app.LampFunctionality_47.Color = [1,0,0];
            
            app.Switch_48.Value = 'Off';
            app.LampFunctionality_48.Color = [1,0,0];
            
            app.Switch_49.Value = 'On';
            app.LampFunctionality_49.Color = [0,1,0];
            app.sensors(49) = 1;
            
            app.Switch_50.Value = 'On';
            app.LampFunctionality_50.Color = [0,1,0];
            app.sensors(50) = 1;

            app.Switch_51.Value = 'Off';
            app.LampFunctionality_51.Color = [1,0,0];
            
            app.Switch_52.Value = 'On';
            app.LampFunctionality_52.Color = [0,1,0];
            app.sensors(52) = 1;
            
            app.Switch_53.Value = 'Off';
            app.LampFunctionality_53.Color = [1,0,0];
            
            app.Switch_54.Value = 'Off';
            app.LampFunctionality_54.Color = [1,0,0];
            
            app.Switch_55.Value = 'Off';
            app.LampFunctionality_55.Color = [1,0,0];
            
            app.Switch_56.Value = 'On';
            app.LampFunctionality_56.Color = [0,1,0];
            app.sensors(56) = 1;
            
            app.Switch_57.Value = 'On';
            app.LampFunctionality_57.Color = [0,1,0];
            app.sensors(57) = 1;
            
            app.Switch_58.Value = 'Off';
            app.LampFunctionality_58.Color = [1,0,0];

            app.Switch_59.Value = 'On';
            app.LampFunctionality_59.Color = [0,1,0];
            app.sensors(59) = 1;

            app.Switch_60.Value = 'Off';
            app.LampFunctionality_60.Color = [1,0,0];

            app.Switch_61.Value = 'Off';
            app.LampFunctionality_61.Color = [1,0,0];
            
            app.Switch_62.Value = 'Off';
            app.LampFunctionality_62.Color = [1,0,0];

            app.Switch_63.Value = 'On';
            app.LampFunctionality_63.Color = [0,1,0];
            app.sensors(63) = 1;

            app.Switch_64.Value = 'Off';
            app.LampFunctionality_64.Color = [1,0,0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NestedSensorArraySystemLabel = uilabel(app.UIFigure);
            app.NestedSensorArraySystemLabel.Text = 'Nested Sensor Array System';
            app.Switch_1 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @Switch_1ValueChanged, true);
            app.Switch_1.Value = 'On';
            app.Switch_2 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'On';
            app.Switch_3 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'On';
            app.Switch_4 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'On';
            app.Switch_5 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'On';
            app.Switch_6 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = 'On';
            app.Switch_7 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = 'On';
            app.Switch_8 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_8.ValueChangedFcn = createCallbackFcn(app, @Switch_8ValueChanged, true);
            app.Switch_8.Value = 'On';
            app.Switch_9 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_9.ValueChangedFcn = createCallbackFcn(app, @Switch_9ValueChanged, true);
            app.Switch_9.Value = 'On';
            app.Switch_10 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_10.ValueChangedFcn = createCallbackFcn(app, @Switch_10ValueChanged, true);
            app.Switch_10.Value = 'On';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '1';
            app.LampFunctionality_1 = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '2';
            app.LampFunctionality_2 = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '3';
            app.LampFunctionality_3 = uilamp(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '4';
            app.LampFunctionality_4 = uilamp(app.UIFigure);
            app.Lamp_5Label = uilabel(app.UIFigure);
            app.Lamp_5Label.Text = '5';
            app.LampFunctionality_5 = uilamp(app.UIFigure);
            app.Lamp_6Label = uilabel(app.UIFigure);
            app.Lamp_6Label.Text = '6';
            app.LampFunctionality_6 = uilamp(app.UIFigure);
            app.Lamp_7Label = uilabel(app.UIFigure);
            app.Lamp_7Label.Text = '7';
            app.LampFunctionality_7 = uilamp(app.UIFigure);
            app.Lamp_8Label = uilabel(app.UIFigure);
            app.Lamp_8Label.Text = '8';
            app.LampFunctionality_8 = uilamp(app.UIFigure);
            app.Lamp_9Label = uilabel(app.UIFigure);
            app.Lamp_9Label.Text = '9';
            app.LampFunctionality_9 = uilamp(app.UIFigure);
            app.Lamp_10Label = uilabel(app.UIFigure);
            app.Lamp_10Label.Text = '10';
            app.LampFunctionality_10 = uilamp(app.UIFigure);
            app.CheckConfigurationButton = uibutton(app.UIFigure, 'push');
            app.CheckConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckConfigurationButtonPushed, true);
            app.CheckConfigurationButton.Text = 'Check Configuration';
            app.ResetAllSensorsButton = uibutton(app.UIFigure, 'push');
            app.ResetAllSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllSensorsButtonPushed, true);
            app.ResetAllSensorsButton.Text = 'Reset All Sensors';
            app.Lamp_11Label = uilabel(app.UIFigure);
            app.Lamp_11Label.Text = '1';
            app.LampUseState_1 = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2';
            app.LampUseState_2 = uilamp(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '4';
            app.LampUseState_4 = uilamp(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '3';
            app.LampUseState_3 = uilamp(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '6';
            app.LampUseState_6 = uilamp(app.UIFigure);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '5';
            app.LampUseState_5 = uilamp(app.UIFigure);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '10';
            app.LampUseState_10 = uilamp(app.UIFigure);
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '9';
            app.LampUseState_9 = uilamp(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '8';
            app.LampUseState_8 = uilamp(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '7';
            app.LampUseState_7 = uilamp(app.UIFigure);
            app.Switch_11 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_11.ValueChangedFcn = createCallbackFcn(app, @Switch_11ValueChanged, true);
            app.Switch_11.Value = 'On';
            app.Switch_12 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_12.ValueChangedFcn = createCallbackFcn(app, @Switch_12ValueChanged, true);
            app.Switch_12.Value = 'On';
            app.Switch_13 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_13.ValueChangedFcn = createCallbackFcn(app, @Switch_13ValueChanged, true);
            app.Switch_13.Value = 'On';
            app.Switch_14 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_14.ValueChangedFcn = createCallbackFcn(app, @Switch_14ValueChanged, true);
            app.Switch_14.Value = 'On';
            app.Switch_15 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_15.ValueChangedFcn = createCallbackFcn(app, @Switch_15ValueChanged, true);
            app.Switch_15.Value = 'On';
            app.Switch_16 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_16.ValueChangedFcn = createCallbackFcn(app, @Switch_16ValueChanged, true);
            app.Switch_16.Value = 'On';
            app.Switch_17 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_17.ValueChangedFcn = createCallbackFcn(app, @Switch_17ValueChanged, true);
            app.Switch_17.Value = 'On';
            app.Switch_18 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_18.ValueChangedFcn = createCallbackFcn(app, @Switch_18ValueChanged, true);
            app.Switch_18.Value = 'On';
            app.Switch_19 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_19.ValueChangedFcn = createCallbackFcn(app, @Switch_19ValueChanged, true);
            app.Switch_19.Value = 'On';
            app.Switch_20 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_20.ValueChangedFcn = createCallbackFcn(app, @Switch_20ValueChanged, true);
            app.Switch_20.Value = 'On';
            app.Label_25 = uilabel(app.UIFigure);
            app.Label_25.Text = '11';
            app.LampFunctionality_11 = uilamp(app.UIFigure);
            app.Label_27 = uilabel(app.UIFigure);
            app.Label_27.Text = '12';
            app.LampFunctionality_12 = uilamp(app.UIFigure);
            app.Label_28 = uilabel(app.UIFigure);
            app.Label_28.Text = '13';
            app.LampFunctionality_13 = uilamp(app.UIFigure);
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '14';
            app.LampFunctionality_14 = uilamp(app.UIFigure);
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '15';
            app.LampFunctionality_15 = uilamp(app.UIFigure);
            app.Label_30 = uilabel(app.UIFigure);
            app.Label_30.Text = '16';
            app.LampFunctionality_16 = uilamp(app.UIFigure);
            app.Label_31 = uilabel(app.UIFigure);
            app.Label_31.Text = '17';
            app.LampFunctionality_17 = uilamp(app.UIFigure);
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Text = '18';
            app.LampFunctionality_18 = uilamp(app.UIFigure);
            app.Label_33 = uilabel(app.UIFigure);
            app.Label_33.Text = '19';
            app.LampFunctionality_19 = uilamp(app.UIFigure);
            app.Label_34 = uilabel(app.UIFigure);
            app.Label_34.Text = '20';
            app.LampFunctionality_20 = uilamp(app.UIFigure);
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '11';
            app.LampUseState_11 = uilamp(app.UIFigure);
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '12';
            app.LampUseState_12 = uilamp(app.UIFigure);
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '13';
            app.LampUseState_13 = uilamp(app.UIFigure);
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '14';
            app.LampUseState_14 = uilamp(app.UIFigure);
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '15';
            app.LampUseState_15 = uilamp(app.UIFigure);
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '16';
            app.LampUseState_16 = uilamp(app.UIFigure);
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '17';
            app.LampUseState_17 = uilamp(app.UIFigure);
            app.Label_22 = uilabel(app.UIFigure);
            app.Label_22.Text = '18';
            app.LampUseState_18 = uilamp(app.UIFigure);
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '19';
            app.LampUseState_19 = uilamp(app.UIFigure);
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '20';
            app.LampUseState_20 = uilamp(app.UIFigure);
            app.Switch_21 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_21.ValueChangedFcn = createCallbackFcn(app, @Switch_21ValueChanged, true);
            app.Switch_21.Value = 'On';
            app.Switch_22 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_22.ValueChangedFcn = createCallbackFcn(app, @Switch_22ValueChanged, true);
            app.Switch_22.Value = 'On';
            app.Switch_23 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_23.ValueChangedFcn = createCallbackFcn(app, @Switch_23ValueChanged, true);
            app.Switch_23.Value = 'On';
            app.Switch_24 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_24.ValueChangedFcn = createCallbackFcn(app, @Switch_24ValueChanged, true);
            app.Switch_24.Value = 'On';
            app.Switch_25 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_25.ValueChangedFcn = createCallbackFcn(app, @Switch_25ValueChanged, true);
            app.Switch_25.Value = 'On';
            app.Switch_26 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_26.ValueChangedFcn = createCallbackFcn(app, @Switch_26ValueChanged, true);
            app.Switch_26.Value = 'On';
            app.Switch_27 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_27.ValueChangedFcn = createCallbackFcn(app, @Switch_27ValueChanged, true);
            app.Switch_27.Value = 'On';
            app.Switch_28 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_28.ValueChangedFcn = createCallbackFcn(app, @Switch_28ValueChanged, true);
            app.Switch_28.Value = 'On';
            app.Switch_29 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_29.ValueChangedFcn = createCallbackFcn(app, @Switch_29ValueChanged, true);
            app.Switch_29.Value = 'On';
            app.Switch_30 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_30.ValueChangedFcn = createCallbackFcn(app, @Switch_30ValueChanged, true);
            app.Switch_30.Value = 'On';
            app.Switch_31 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_31.ValueChangedFcn = createCallbackFcn(app, @Switch_31ValueChanged, true);
            app.Switch_31.Value = 'On';
            app.Switch_32 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_32.ValueChangedFcn = createCallbackFcn(app, @Switch_32ValueChanged, true);
            app.Switch_32.Value = 'On';
            app.Switch_33 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_33.ValueChangedFcn = createCallbackFcn(app, @Switch_33ValueChanged, true);
            app.Switch_33.Value = 'On';
            app.Switch_34 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_34.ValueChangedFcn = createCallbackFcn(app, @Switch_34ValueChanged, true);
            app.Switch_34.Value = 'On';
            app.Switch_35 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_35.ValueChangedFcn = createCallbackFcn(app, @Switch_35ValueChanged, true);
            app.Switch_35.Value = 'On';
            app.Switch_36 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_36.ValueChangedFcn = createCallbackFcn(app, @Switch_36ValueChanged, true);
            app.Switch_36.Value = 'On';
            app.Switch_37 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_37.ValueChangedFcn = createCallbackFcn(app, @Switch_37ValueChanged, true);
            app.Switch_37.Value = 'On';
            app.Switch_38 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_38.ValueChangedFcn = createCallbackFcn(app, @Switch_38ValueChanged, true);
            app.Switch_38.Value = 'On';
            app.Switch_39 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_39.ValueChangedFcn = createCallbackFcn(app, @Switch_39ValueChanged, true);
            app.Switch_39.Value = 'On';
            app.Switch_40 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_40.ValueChangedFcn = createCallbackFcn(app, @Switch_40ValueChanged, true);
            app.Switch_40.Value = 'On';
            app.Label_65 = uilabel(app.UIFigure);
            app.Label_65.Text = '21';
            app.LampFunctionality_21 = uilamp(app.UIFigure);
            app.Label_66 = uilabel(app.UIFigure);
            app.Label_66.Text = '22';
            app.LampFunctionality_22 = uilamp(app.UIFigure);
            app.Label_67 = uilabel(app.UIFigure);
            app.Label_67.Text = '23';
            app.LampFunctionality_23 = uilamp(app.UIFigure);
            app.Label_35 = uilabel(app.UIFigure);
            app.Label_35.Text = '24';
            app.LampFunctionality_24 = uilamp(app.UIFigure);
            app.Label_68 = uilabel(app.UIFigure);
            app.Label_68.Text = '25';
            app.LampFunctionality_25 = uilamp(app.UIFigure);
            app.Label_69 = uilabel(app.UIFigure);
            app.Label_69.Text = '26';
            app.LampFunctionality_26 = uilamp(app.UIFigure);
            app.Label_70 = uilabel(app.UIFigure);
            app.Label_70.Text = '27';
            app.LampFunctionality_27 = uilamp(app.UIFigure);
            app.Label_71 = uilabel(app.UIFigure);
            app.Label_71.Text = '28';
            app.LampFunctionality_28 = uilamp(app.UIFigure);
            app.Label_72 = uilabel(app.UIFigure);
            app.Label_72.Text = '29';
            app.LampFunctionality_29 = uilamp(app.UIFigure);
            app.Label_73 = uilabel(app.UIFigure);
            app.Label_73.Text = '30';
            app.LampFunctionality_30 = uilamp(app.UIFigure);
            app.Label_74 = uilabel(app.UIFigure);
            app.Label_74.Text = '21';
            app.LampUseState_21 = uilamp(app.UIFigure);
            app.Label_36 = uilabel(app.UIFigure);
            app.Label_36.Text = '22';
            app.LampUseState_22 = uilamp(app.UIFigure);
            app.Label_37 = uilabel(app.UIFigure);
            app.Label_37.Text = '23';
            app.LampUseState_23 = uilamp(app.UIFigure);
            app.Label_38 = uilabel(app.UIFigure);
            app.Label_38.Text = '24';
            app.LampUseState_24 = uilamp(app.UIFigure);
            app.Label_39 = uilabel(app.UIFigure);
            app.Label_39.Text = '25';
            app.LampUseState_25 = uilamp(app.UIFigure);
            app.Label_40 = uilabel(app.UIFigure);
            app.Label_40.Text = '26';
            app.LampUseState_26 = uilamp(app.UIFigure);
            app.Label_41 = uilabel(app.UIFigure);
            app.Label_41.Text = '27';
            app.LampUseState_27 = uilamp(app.UIFigure);
            app.Label_42 = uilabel(app.UIFigure);
            app.Label_42.Text = '28';
            app.LampUseState_28 = uilamp(app.UIFigure);
            app.Label_43 = uilabel(app.UIFigure);
            app.Label_43.Text = '29';
            app.LampUseState_29 = uilamp(app.UIFigure);
            app.Label_44 = uilabel(app.UIFigure);
            app.Label_44.Text = '30';
            app.LampUseState_30 = uilamp(app.UIFigure);
            app.Label_45 = uilabel(app.UIFigure);
            app.Label_45.Text = '31';
            app.LampFunctionality_31 = uilamp(app.UIFigure);
            app.Label_46 = uilabel(app.UIFigure);
            app.Label_46.Text = '32';
            app.LampFunctionality_32 = uilamp(app.UIFigure);
            app.Label_47 = uilabel(app.UIFigure);
            app.Label_47.Text = '33';
            app.LampFunctionality_33 = uilamp(app.UIFigure);
            app.Label_48 = uilabel(app.UIFigure);
            app.Label_48.Text = '34';
            app.LampFunctionality_34 = uilamp(app.UIFigure);
            app.Label_49 = uilabel(app.UIFigure);
            app.Label_49.Text = '35';
            app.LampFunctionality_35 = uilamp(app.UIFigure);
            app.Label_50 = uilabel(app.UIFigure);
            app.Label_50.Text = '36';
            app.LampFunctionality_36 = uilamp(app.UIFigure);
            app.Label_51 = uilabel(app.UIFigure);
            app.Label_51.Text = '37';
            app.LampFunctionality_37 = uilamp(app.UIFigure);
            app.Label_52 = uilabel(app.UIFigure);
            app.Label_52.Text = '38';
            app.LampFunctionality_38 = uilamp(app.UIFigure);
            app.Label_53 = uilabel(app.UIFigure);
            app.Label_53.Text = '39';
            app.LampFunctionality_39 = uilamp(app.UIFigure);
            app.Label_54 = uilabel(app.UIFigure);
            app.Label_54.Text = '40';
            app.LampFunctionality_40 = uilamp(app.UIFigure);
            app.Label_55 = uilabel(app.UIFigure);
            app.Label_55.Text = '31';
            app.LampUseState_31 = uilamp(app.UIFigure);
            app.Label_56 = uilabel(app.UIFigure);
            app.Label_56.Text = '32';
            app.LampUseState_32 = uilamp(app.UIFigure);
            app.Label_57 = uilabel(app.UIFigure);
            app.Label_57.Text = '33';
            app.LampUseState_33 = uilamp(app.UIFigure);
            app.Label_58 = uilabel(app.UIFigure);
            app.Label_58.Text = '34';
            app.LampUseState_34 = uilamp(app.UIFigure);
            app.Label_59 = uilabel(app.UIFigure);
            app.Label_59.Text = '35';
            app.LampUseState_35 = uilamp(app.UIFigure);
            app.Label_60 = uilabel(app.UIFigure);
            app.Label_60.Text = '36';
            app.LampUseState_36 = uilamp(app.UIFigure);
            app.Label_61 = uilabel(app.UIFigure);
            app.Label_61.Text = '37';
            app.LampUseState_37 = uilamp(app.UIFigure);
            app.Label_62 = uilabel(app.UIFigure);
            app.Label_62.Text = '38';
            app.LampUseState_38 = uilamp(app.UIFigure);
            app.Label_63 = uilabel(app.UIFigure);
            app.Label_63.Text = '39';
            app.LampUseState_39 = uilamp(app.UIFigure);
            app.Label_64 = uilabel(app.UIFigure);
            app.Label_64.Text = '40';
            app.LampUseState_40 = uilamp(app.UIFigure);
            app.Switch_41 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_41.ValueChangedFcn = createCallbackFcn(app, @Switch_41ValueChanged, true);
            app.Switch_41.Value = 'On';
            app.Switch_42 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_42.ValueChangedFcn = createCallbackFcn(app, @Switch_42ValueChanged, true);
            app.Switch_42.Value = 'On';
            app.Switch_43 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_43.ValueChangedFcn = createCallbackFcn(app, @Switch_43ValueChanged, true);
            app.Switch_43.Value = 'On';
            app.Switch_44 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_44.ValueChangedFcn = createCallbackFcn(app, @Switch_44ValueChanged, true);
            app.Switch_44.Value = 'On';
            app.Switch_45 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_45.ValueChangedFcn = createCallbackFcn(app, @Switch_45ValueChanged, true);
            app.Switch_45.Value = 'On';
            app.Switch_46 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_46.ValueChangedFcn = createCallbackFcn(app, @Switch_46ValueChanged, true);
            app.Switch_46.Value = 'On';
            app.Switch_47 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_47.ValueChangedFcn = createCallbackFcn(app, @Switch_47ValueChanged, true);
            app.Switch_47.Value = 'On';
            app.Switch_48 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_48.ValueChangedFcn = createCallbackFcn(app, @Switch_48ValueChanged, true);
            app.Switch_48.Value = 'On';
            app.Switch_49 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_49.ValueChangedFcn = createCallbackFcn(app, @Switch_49ValueChanged, true);
            app.Switch_49.Value = 'On';
            app.Switch_50 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_50.ValueChangedFcn = createCallbackFcn(app, @Switch_50ValueChanged, true);
            app.Switch_50.Value = 'On';
            app.Switch_51 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_51.ValueChangedFcn = createCallbackFcn(app, @Switch_51ValueChanged, true);
            app.Switch_51.Value = 'On';
            app.Switch_52 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_52.ValueChangedFcn = createCallbackFcn(app, @Switch_52ValueChanged, true);
            app.Switch_52.Value = 'On';
            app.Switch_53 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_53.ValueChangedFcn = createCallbackFcn(app, @Switch_53ValueChanged, true);
            app.Switch_53.Value = 'On';
            app.Switch_54 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_54.ValueChangedFcn = createCallbackFcn(app, @Switch_54ValueChanged, true);
            app.Switch_54.Value = 'On';
            app.Switch_55 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_55.ValueChangedFcn = createCallbackFcn(app, @Switch_55ValueChanged, true);
            app.Switch_55.Value = 'On';
            app.Switch_56 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_56.ValueChangedFcn = createCallbackFcn(app, @Switch_56ValueChanged, true);
            app.Switch_56.Value = 'On';
            app.Switch_57 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_57.ValueChangedFcn = createCallbackFcn(app, @Switch_57ValueChanged, true);
            app.Switch_57.Value = 'On';
            app.Switch_58 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_58.ValueChangedFcn = createCallbackFcn(app, @Switch_58ValueChanged, true);
            app.Switch_58.Value = 'On';
            app.Switch_59 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_59.ValueChangedFcn = createCallbackFcn(app, @Switch_59ValueChanged, true);
            app.Switch_59.Value = 'On';
            app.Switch_60 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_60.ValueChangedFcn = createCallbackFcn(app, @Switch_60ValueChanged, true);
            app.Switch_60.Value = 'On';
            app.Switch_61 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_61.ValueChangedFcn = createCallbackFcn(app, @Switch_61ValueChanged, true);
            app.Switch_61.Value = 'On';
            app.Switch_62 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_62.ValueChangedFcn = createCallbackFcn(app, @Switch_62ValueChanged, true);
            app.Switch_62.Value = 'On';
            app.Switch_63 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_63.ValueChangedFcn = createCallbackFcn(app, @Switch_63ValueChanged, true);
            app.Switch_63.Value = 'On';
            app.Switch_64 = uiswitch(app.UIFigure, 'rocker');
            app.Switch_64.ValueChangedFcn = createCallbackFcn(app, @Switch_64ValueChanged, true);
            app.Switch_64.Value = 'On';
            app.Label_113 = uilabel(app.UIFigure);
            app.Label_113.Text = '41';
            app.LampFunctionality_41 = uilamp(app.UIFigure);
            app.Label_114 = uilabel(app.UIFigure);
            app.Label_114.Text = '42';
            app.LampFunctionality_42 = uilamp(app.UIFigure);
            app.Label_115 = uilabel(app.UIFigure);
            app.Label_115.Text = '43';
            app.LampFunctionality_43 = uilamp(app.UIFigure);
            app.Label_75 = uilabel(app.UIFigure);
            app.Label_75.Text = '44';
            app.LampFunctionality_44 = uilamp(app.UIFigure);
            app.Label_116 = uilabel(app.UIFigure);
            app.Label_116.Text = '45';
            app.LampFunctionality_45 = uilamp(app.UIFigure);
            app.Label_117 = uilabel(app.UIFigure);
            app.Label_117.Text = '46';
            app.LampFunctionality_46 = uilamp(app.UIFigure);
            app.Label_118 = uilabel(app.UIFigure);
            app.Label_118.Text = '47';
            app.LampFunctionality_47 = uilamp(app.UIFigure);
            app.Label_119 = uilabel(app.UIFigure);
            app.Label_119.Text = '48';
            app.LampFunctionality_48 = uilamp(app.UIFigure);
            app.Label_120 = uilabel(app.UIFigure);
            app.Label_120.Text = '49';
            app.LampFunctionality_49 = uilamp(app.UIFigure);
            app.Label_121 = uilabel(app.UIFigure);
            app.Label_121.Text = '50';
            app.LampFunctionality_50 = uilamp(app.UIFigure);
            app.Label_122 = uilabel(app.UIFigure);
            app.Label_122.Text = '41';
            app.LampUseState_41 = uilamp(app.UIFigure);
            app.Label_76 = uilabel(app.UIFigure);
            app.Label_76.Text = '42';
            app.LampUseState_42 = uilamp(app.UIFigure);
            app.Label_77 = uilabel(app.UIFigure);
            app.Label_77.Text = '43';
            app.LampUseState_43 = uilamp(app.UIFigure);
            app.Label_78 = uilabel(app.UIFigure);
            app.Label_78.Text = '44';
            app.LampUseState_44 = uilamp(app.UIFigure);
            app.Label_79 = uilabel(app.UIFigure);
            app.Label_79.Text = '45';
            app.LampUseState_45 = uilamp(app.UIFigure);
            app.Label_80 = uilabel(app.UIFigure);
            app.Label_80.Text = '46';
            app.LampUseState_46 = uilamp(app.UIFigure);
            app.Label_81 = uilabel(app.UIFigure);
            app.Label_81.Text = '47';
            app.LampUseState_47 = uilamp(app.UIFigure);
            app.Label_82 = uilabel(app.UIFigure);
            app.Label_82.Text = '48';
            app.LampUseState_48 = uilamp(app.UIFigure);
            app.Label_83 = uilabel(app.UIFigure);
            app.Label_83.Text = '49';
            app.LampUseState_49 = uilamp(app.UIFigure);
            app.Label_84 = uilabel(app.UIFigure);
            app.Label_84.Text = '50';
            app.LampUseState_50 = uilamp(app.UIFigure);
            app.Label_85 = uilabel(app.UIFigure);
            app.Label_85.Text = '51';
            app.LampFunctionality_51 = uilamp(app.UIFigure);
            app.Label_86 = uilabel(app.UIFigure);
            app.Label_86.Text = '52';
            app.LampFunctionality_52 = uilamp(app.UIFigure);
            app.Label_87 = uilabel(app.UIFigure);
            app.Label_87.Text = '53';
            app.LampFunctionality_53 = uilamp(app.UIFigure);
            app.Label_88 = uilabel(app.UIFigure);
            app.Label_88.Text = '54';
            app.LampFunctionality_54 = uilamp(app.UIFigure);
            app.Label_89 = uilabel(app.UIFigure);
            app.Label_89.Text = '55';
            app.LampFunctionality_55 = uilamp(app.UIFigure);
            app.Label_90 = uilabel(app.UIFigure);
            app.Label_90.Text = '56';
            app.LampFunctionality_56 = uilamp(app.UIFigure);
            app.Label_91 = uilabel(app.UIFigure);
            app.Label_91.Text = '57';
            app.LampFunctionality_57 = uilamp(app.UIFigure);
            app.Label_92 = uilabel(app.UIFigure);
            app.Label_92.Text = '58';
            app.LampFunctionality_58 = uilamp(app.UIFigure);
            app.Label_93 = uilabel(app.UIFigure);
            app.Label_93.Text = '59';
            app.LampFunctionality_59 = uilamp(app.UIFigure);
            app.Label_94 = uilabel(app.UIFigure);
            app.Label_94.Text = '60';
            app.LampFunctionality_60 = uilamp(app.UIFigure);
            app.Label_95 = uilabel(app.UIFigure);
            app.Label_95.Text = '51';
            app.LampUseState_51 = uilamp(app.UIFigure);
            app.Label_96 = uilabel(app.UIFigure);
            app.Label_96.Text = '52';
            app.LampUseState_52 = uilamp(app.UIFigure);
            app.Label_97 = uilabel(app.UIFigure);
            app.Label_97.Text = '53';
            app.LampUseState_53 = uilamp(app.UIFigure);
            app.Label_98 = uilabel(app.UIFigure);
            app.Label_98.Text = '54';
            app.LampUseState_54 = uilamp(app.UIFigure);
            app.Label_99 = uilabel(app.UIFigure);
            app.Label_99.Text = '55';
            app.LampUseState_55 = uilamp(app.UIFigure);
            app.Label_100 = uilabel(app.UIFigure);
            app.Label_100.Text = '56';
            app.LampUseState_56 = uilamp(app.UIFigure);
            app.Label_101 = uilabel(app.UIFigure);
            app.Label_101.Text = '57';
            app.LampUseState_57 = uilamp(app.UIFigure);
            app.Label_102 = uilabel(app.UIFigure);
            app.Label_102.Text = '58';
            app.LampUseState_58 = uilamp(app.UIFigure);
            app.Label_103 = uilabel(app.UIFigure);
            app.Label_103.Text = '59';
            app.LampUseState_59 = uilamp(app.UIFigure);
            app.Label_104 = uilabel(app.UIFigure);
            app.Label_104.Text = '20';
            app.LampUseState_60 = uilamp(app.UIFigure);
            app.Label_105 = uilabel(app.UIFigure);
            app.Label_105.Text = '61';
            app.LampFunctionality_61 = uilamp(app.UIFigure);
            app.Label_106 = uilabel(app.UIFigure);
            app.Label_106.Text = '62';
            app.LampFunctionality_62 = uilamp(app.UIFigure);
            app.Label_107 = uilabel(app.UIFigure);
            app.Label_107.Text = '63';
            app.LampFunctionality_63 = uilamp(app.UIFigure);
            app.Label_108 = uilabel(app.UIFigure);
            app.Label_108.Text = '64';
            app.LampFunctionality_64 = uilamp(app.UIFigure);
            app.Label_109 = uilabel(app.UIFigure);
            app.Label_109.Text = '61';
            app.LampUseState_61 = uilamp(app.UIFigure);
            app.Label_110 = uilabel(app.UIFigure);
            app.Label_110.Text = '62';
            app.LampUseState_62 = uilamp(app.UIFigure);
            app.Label_111 = uilabel(app.UIFigure);
            app.Label_111.Text = '63';
            app.LampUseState_63 = uilamp(app.UIFigure);
            app.Label_112 = uilabel(app.UIFigure);
            app.Label_112.Text = '64';
            app.LampUseState_64 = uilamp(app.UIFigure);
            app.SNRdBSliderLabel = uilabel(app.UIFigure);
            app.SNRdBSliderLabel.Text = 'SNR [dB]';
            app.SNRdBSlider = uislider(app.UIFigure);
            app.SNRdBSlider.Limits = [-20 50];
            app.SNRdBSlider.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50];
            app.SNRdBSlider.MajorTickLabels = {'-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.SNRdBSlider.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.AngleSliderLabel = uilabel(app.UIFigure);
            app.AngleSliderLabel.Text = 'Angle [°]';
            app.AngleSlider = uislider(app.UIFigure);
            app.AngleSlider.Limits = [0 180];
            app.AngleSlider.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.AngleSlider.MajorTickLabels = {'0', '15', '30', '45', '60', '75', '90', '105', '120', '135', '150', '165', '180'};
            app.FrequencyHzSliderLabel = uilabel(app.UIFigure);
            app.FrequencyHzSliderLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzSlider = uislider(app.UIFigure);
            app.FrequencyHzSlider.Limits = [1000 5000];
            app.FrequencyHzSlider.Value = 1000;
            app.NSAbadPSLButton = uibutton(app.UIFigure, 'push');
            app.NSAbadPSLButton.ButtonPushedFcn = createCallbackFcn(app, @NSAbadPSLButtonPushed, true);
            app.NSAbadPSLButton.Text = 'NSA, bad PSL';
            app.NotanNSAButton = uibutton(app.UIFigure, 'push');
            app.NotanNSAButton.ButtonPushedFcn = createCallbackFcn(app, @NotanNSAButtonPushed, true);
            app.NotanNSAButton.Text = 'Not an NSA';
            app.FailAllSensorsButton = uibutton(app.UIFigure, 'push');
            app.FailAllSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @FailAllSensorsButtonPushed, true);
            app.FailAllSensorsButton.Text = 'Fail All Sensors';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sensorsScreen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end